classdef analysis2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AerodynamicModelDropDown       matlab.ui.control.DropDown
        AerodynamicModelDropDownLabel  matlab.ui.control.Label
        AnalysisTypeDropDown           matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel      matlab.ui.control.Label
        ReEditField_2                  matlab.ui.control.NumericEditField
        ReEditField_2Label             matlab.ui.control.Label
        EnableCheckBox_Re_2            matlab.ui.control.CheckBox
        dReEditField                   matlab.ui.control.NumericEditField
        dReEditFieldLabel              matlab.ui.control.Label
        dMaEditField                   matlab.ui.control.NumericEditField
        dMaEditFieldLabel              matlab.ui.control.Label
        MaEditField                    matlab.ui.control.NumericEditField
        MaEditFieldLabel               matlab.ui.control.Label
        MachNoLabel                    matlab.ui.control.Label
        PolarAnalysisLabel             matlab.ui.control.Label
        MinEditField_Re_2              matlab.ui.control.NumericEditField
        MinEditField_3Label_2          matlab.ui.control.Label
        MaxEditField_Re_2              matlab.ui.control.NumericEditField
        MaxEditField_2Label_2          matlab.ui.control.Label
        MinEditField_Re                matlab.ui.control.NumericEditField
        MinEditField_3Label            matlab.ui.control.Label
        MaxEditField_Re                matlab.ui.control.NumericEditField
        MaxEditField_2Label            matlab.ui.control.Label
        EnableCheckBox_Ma              matlab.ui.control.CheckBox
        EnableCheckBox_Re              matlab.ui.control.CheckBox
        Lamp                           matlab.ui.control.Lamp
        dalphaEditField                matlab.ui.control.NumericEditField
        dalphaEditFieldLabel           matlab.ui.control.Label
        AddButton                      matlab.ui.control.Button
        NewAirfoilEditField            matlab.ui.control.EditField
        NewAirfoilEditFieldLabel       matlab.ui.control.Label
        AnalysisButton                 matlab.ui.control.Button
        SelectDropDown                 matlab.ui.control.DropDown
        SelectDropDownLabel            matlab.ui.control.Label
        alphaEditField                 matlab.ui.control.NumericEditField
        alphaEditFieldLabel            matlab.ui.control.Label
        ReynoldNoLabel                 matlab.ui.control.Label
        MinEditField_2                 matlab.ui.control.NumericEditField
        MinEditField_2Label            matlab.ui.control.Label
        AngleofAttackLabel             matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.NumericEditField
        MaxLabel                       matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        DAirfoilAnalysisLabel          matlab.ui.control.Label
        airfoil                        matlab.ui.control.UIAxes
        UI_CM                          matlab.ui.control.UIAxes
        UI_CD                          matlab.ui.control.UIAxes
        UI_CL                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function errorMessageBox(app, error_msg, error_type)
            uialert(app.UIFigure, error_msg,error_type);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            airfoil_name = app.SelectDropDown.Value;            
            min_alpha = app.MinEditField_2.Value;
            max_alpha = app.MaxEditField.Value;
            Re = app.alphaEditField.Value;
            d_alpha = app.dalphaEditField.Value;

            analysis_type = app.AnalysisTypeDropDown.Value;
            aero_model = app.AerodynamicModelDropDown.Value;

            
            if max_alpha > 180 || max_alpha < -180
                errorMessageBox(app, "Max. alpha outside the range, acceptable range [-180, 180]", "Invalid parameteres")
                return;
            elseif min_alpha > 180 || min_alpha < -180
                errorMessageBox(app, "Min. alpha outside the range, acceptable range [-180, 180]", "Invalid parameteres")
                return;
            elseif min_alpha == max_alpha
                errorMessageBox(app, "Min & Max alpha are equal", "Invalid parameteres")
                return;
            elseif Re < 1-3 || Re > 1e9
                errorMessageBox(app, "Reynolds number outside the range, acceptable range [1e-3, 1e9]", "Invalid parameteres")
                return;
            elseif  d_alpha < 1e-3
                errorMessageBox(app, "Alpha step should be higher than 1e-3", "Invalid parameteres")
                return;
            end

            % generate data structrure

            alpha = min_alpha:d_alpha:max_alpha;
            l_alpha = length(alpha);

            CL = zeros(1, l_alpha);
            CD = zeros(1, l_alpha);
            CM = zeros(1, l_alpha);
            conf = zeros(1, l_alpha);

            app.Lamp.Color = 'r';
            drawnow;

            for i = 1:l_alpha
                [CL(i), CD(i), CM(i), conf(i)] = NeuralFoil(airfoil_name, Re, 0, alpha(i));
                
                if i == floor(l_alpha / 2)
                    app.Lamp.Color = 'yellow';
                    drawnow;
                end

            end
            
            app.Lamp.Color = 'g';

            % Plot CL vs alpha in the first UIAxes
            plot(app.UI_CL, alpha, CL, 'b', 'LineWidth', 1.5);
            hold(app.UI_CL, 'on');
            plot(app.UI_CL, alpha, conf, 'r--', 'LineWidth', 1.2);
            hold(app.UI_CL, 'off');
            grid(app.UI_CL, 'on');
            
            % Plot CD vs alpha in the second UIAxes
            plot(app.UI_CD, alpha, CD, 'b', 'LineWidth', 1.5);
            hold(app.UI_CD, 'on');
            plot(app.UI_CD, alpha, conf, 'r--', 'LineWidth', 1.2);
            hold(app.UI_CD, 'off');
            grid(app.UI_CD, 'on');
            
            % Plot CM vs alpha in the third UIAxes
            plot(app.UI_CM, alpha, CM, 'b', 'LineWidth', 1.5);
            hold(app.UI_CM, 'on');
            plot(app.UI_CM, alpha, conf, 'r--', 'LineWidth', 1.2);
            hold(app.UI_CM, 'off');
            grid(app.UI_CM, 'on');
                        

        end

        % Value changed function: AnalysisTypeDropDown
        function AnalysisTypeDropDownValueChanged(app, event)
            value = app.AnalysisTypeDropDown.Value;
            
        end

        % Value changed function: AerodynamicModelDropDown
        function AerodynamicModelDropDownValueChanged(app, event)
            value = app.AerodynamicModelDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UI_CL = uiaxes(app.UIFigure);
            title(app.UI_CL, 'Lift Coeficient')
            xlabel(app.UI_CL, '\alpha [º]')
            ylabel(app.UI_CL, 'C_L')
            app.UI_CD = uiaxes(app.UIFigure);
            title(app.UI_CD, 'Drag Coefficient')
            xlabel(app.UI_CD, '\alpha [º]')
            ylabel(app.UI_CD, 'C_D')
            zlabel(app.UI_CD, 'Z')
            app.UI_CM = uiaxes(app.UIFigure);
            title(app.UI_CM, 'Pitch Moment Coeficient')
            xlabel(app.UI_CM, '\alpha [º]')
            ylabel(app.UI_CM, 'C_M')
            app.airfoil = uiaxes(app.UIFigure);
            title(app.airfoil, 'Airfoil')
            xlabel(app.airfoil, 'x/c')
            ylabel(app.airfoil, 'y/c')
            app.DAirfoilAnalysisLabel = uilabel(app.UIFigure);
            app.DAirfoilAnalysisLabel.Text = '2D Airfoil Analysis';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '__________________________';
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Text = 'Max.';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField.Value = 25;
            app.AngleofAttackLabel = uilabel(app.UIFigure);
            app.AngleofAttackLabel.Text = 'Angle of Attack';
            app.MinEditField_2Label = uilabel(app.UIFigure);
            app.MinEditField_2Label.Text = 'Min.';
            app.MinEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField_2.Value = -10;
            app.ReynoldNoLabel = uilabel(app.UIFigure);
            app.ReynoldNoLabel.Text = 'Reynold No.';
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Interpreter = 'latex';
            app.alphaEditFieldLabel.Text = '\alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.alphaEditField.Value = 10;
            app.SelectDropDownLabel = uilabel(app.UIFigure);
            app.SelectDropDownLabel.Text = 'Select';
            app.SelectDropDown = uidropdown(app.UIFigure);
            app.SelectDropDown.Items = {'naca0012', 'naca0015', 'naca4412', 'naca2408'};
            app.SelectDropDown.Value = 'naca0012';
            app.AnalysisButton = uibutton(app.UIFigure, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.NewAirfoilEditFieldLabel = uilabel(app.UIFigure);
            app.NewAirfoilEditFieldLabel.Text = 'New Airfoil';
            app.NewAirfoilEditField = uieditfield(app.UIFigure, 'text');
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.Text = 'Add';
            app.dalphaEditFieldLabel = uilabel(app.UIFigure);
            app.dalphaEditFieldLabel.Interpreter = 'latex';
            app.dalphaEditFieldLabel.Text = 'd\alpha';
            app.dalphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.dalphaEditField.Value = 1;
            app.Lamp = uilamp(app.UIFigure);
            app.EnableCheckBox_Re = uicheckbox(app.UIFigure);
            app.EnableCheckBox_Re.Text = 'Enable Multiple Inputs';
            app.EnableCheckBox_Ma = uicheckbox(app.UIFigure);
            app.EnableCheckBox_Ma.Text = 'Enable Multiple Inputs';
            app.MaxEditField_2Label = uilabel(app.UIFigure);
            app.MaxEditField_2Label.Text = 'Max.';
            app.MaxEditField_Re = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField_Re.Value = 25;
            app.MinEditField_3Label = uilabel(app.UIFigure);
            app.MinEditField_3Label.Text = 'Min.';
            app.MinEditField_Re = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField_Re.Value = -10;
            app.MaxEditField_2Label_2 = uilabel(app.UIFigure);
            app.MaxEditField_2Label_2.Text = 'Max.';
            app.MaxEditField_Re_2 = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField_Re_2.Value = 25;
            app.MinEditField_3Label_2 = uilabel(app.UIFigure);
            app.MinEditField_3Label_2.Text = 'Min.';
            app.MinEditField_Re_2 = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField_Re_2.Value = -10;
            app.PolarAnalysisLabel = uilabel(app.UIFigure);
            app.PolarAnalysisLabel.Text = ' Polar Analysis';
            app.MachNoLabel = uilabel(app.UIFigure);
            app.MachNoLabel.Text = 'Mach No.';
            app.MaEditFieldLabel = uilabel(app.UIFigure);
            app.MaEditFieldLabel.Text = 'Ma';
            app.MaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaEditField.Value = 1000000;
            app.dMaEditFieldLabel = uilabel(app.UIFigure);
            app.dMaEditFieldLabel.Text = 'dMa';
            app.dMaEditField = uieditfield(app.UIFigure, 'numeric');
            app.dMaEditField.Value = 1;
            app.dReEditFieldLabel = uilabel(app.UIFigure);
            app.dReEditFieldLabel.Text = 'dRe';
            app.dReEditField = uieditfield(app.UIFigure, 'numeric');
            app.dReEditField.Value = 1;
            app.EnableCheckBox_Re_2 = uicheckbox(app.UIFigure);
            app.EnableCheckBox_Re_2.Text = 'Enable Multiple Inputs';
            app.ReEditField_2Label = uilabel(app.UIFigure);
            app.ReEditField_2Label.Text = 'Re';
            app.ReEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ReEditField_2.Value = 1000000;
            app.AnalysisTypeDropDownLabel = uilabel(app.UIFigure);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.UIFigure);
            app.AnalysisTypeDropDown.Items = {'Single Polar', 'Re Variation', 'Ma Variation'};
            app.AnalysisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropDownValueChanged, true);
            app.AnalysisTypeDropDown.Value = 'Single Polar';
            app.AerodynamicModelDropDownLabel = uilabel(app.UIFigure);
            app.AerodynamicModelDropDownLabel.Text = 'Aerodynamic Model';
            app.AerodynamicModelDropDown = uidropdown(app.UIFigure);
            app.AerodynamicModelDropDown.Items = {'NeuralFoil', 'XFOIL', 'XFLR5'};
            app.AerodynamicModelDropDown.ValueChangedFcn = createCallbackFcn(app, @AerodynamicModelDropDownValueChanged, true);
            app.AerodynamicModelDropDown.Value = 'NeuralFoil';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis2D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end