classdef ASTRAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InfoMenu                        matlab.ui.container.Menu
        GitRepMenu                      matlab.ui.container.Menu
        RUNButton                       matlab.ui.control.Button
        DAEROButton                     matlab.ui.control.Button
        BLADEPLOTButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        RotorTab                        matlab.ui.container.Tab
        lambda_cEditField               matlab.ui.control.NumericEditField
        alpha_cLabel                    matlab.ui.control.Label
        DeltaPsitextNoPointsEditField   matlab.ui.control.NumericEditField
        DeltaPsitextNoPointsLabel       matlab.ui.control.Label
        DeltayNoPointsEditField         matlab.ui.control.NumericEditField
        DeltayNoPointsEditFieldLabel    matlab.ui.control.Label
        SelectairfoilDropDown           matlab.ui.control.DropDown
        SelectairfoilDropDownLabel      matlab.ui.control.Label
        AirfoilLabel                    matlab.ui.control.Label
        NoofBladesSpinner               matlab.ui.control.Spinner
        NoofBladesSpinnerLabel          matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        fracmathrmdthetamathrmdyEditField  matlab.ui.control.NumericEditField
        fracmathrmdthetamathrmdyLabel   matlab.ui.control.Label
        epsilontextEditField            matlab.ui.control.NumericEditField
        epsilonLabel                    matlab.ui.control.Label
        thetatextEditField              matlab.ui.control.NumericEditField
        thetatextEditFieldLabel         matlab.ui.control.Label
        cEditField                      matlab.ui.control.NumericEditField
        cLabel                          matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        bLabel                          matlab.ui.control.Label
        BladeLabel                      matlab.ui.control.Label
        RotorLabel                      matlab.ui.control.Label
        TipLossesCheckBox               matlab.ui.control.CheckBox
        OpenRotorCheckBox               matlab.ui.control.CheckBox
        VehicleTab                      matlab.ui.container.Tab
        VxEditField                     matlab.ui.control.NumericEditField
        VxEditFieldLabel                matlab.ui.control.Label
        phiEditField                    matlab.ui.control.NumericEditField
        phiEditFieldLabel               matlab.ui.control.Label
        thetaEditField                  matlab.ui.control.NumericEditField
        thetaEditFieldLabel             matlab.ui.control.Label
        VzEditField                     matlab.ui.control.NumericEditField
        VzEditFieldLabel                matlab.ui.control.Label
        VyEditField                     matlab.ui.control.NumericEditField
        VyEditFieldLabel                matlab.ui.control.Label
        PitchLabel                      matlab.ui.control.Label
        RollLabel                       matlab.ui.control.Label
        AttitudeLabel                   matlab.ui.control.Label
        VelocitymsLabel                 matlab.ui.control.Label
        DeploymenttimesEditField        matlab.ui.control.NumericEditField
        DeploymenttimesEditFieldLabel   matlab.ui.control.Label
        PayloadMasskgEditField          matlab.ui.control.NumericEditField
        PayloadMasskgEditFieldLabel     matlab.ui.control.Label
        HeightmEditField                matlab.ui.control.NumericEditField
        HeightmEditFieldLabel           matlab.ui.control.Label
        VehicleDragCheckBox             matlab.ui.control.CheckBox
        RotorDeploymentLabel            matlab.ui.control.Label
        VEICHLELabel                    matlab.ui.control.Label
        INITIALCONDITIONSLabel          matlab.ui.control.Label
        SimTab                          matlab.ui.container.Tab
        BladesIntegrationDropDown       matlab.ui.control.DropDown
        BladesIntegrationDropDownLabel  matlab.ui.control.Label
        GravitationalConstantm3kg1s2EditField  matlab.ui.control.NumericEditField
        GravitationalConstantm3kg1s2EditFieldLabel  matlab.ui.control.Label
        EarthMasskgEditField            matlab.ui.control.NumericEditField
        EarthMasskgEditFieldLabel       matlab.ui.control.Label
        EarthRadiusmEditField           matlab.ui.control.NumericEditField
        EarthRadiusmEditFieldLabel      matlab.ui.control.Label
        SolverModelsLabel               matlab.ui.control.Label
        AerodynamicModelDropDown        matlab.ui.control.DropDown
        AerodynamicModelDropDown_2Label  matlab.ui.control.Label
        AtmosphericModelDropDown        matlab.ui.control.DropDown
        AtmosphericModelDropDownLabel   matlab.ui.control.Label
        ResidualValueEditField          matlab.ui.control.NumericEditField
        ResidualValueEditFieldLabel     matlab.ui.control.Label
        ODESolverDropDown               matlab.ui.control.DropDown
        ODESolverDropDownLabel          matlab.ui.control.Label
        TimeLimitsEditField             matlab.ui.control.NumericEditField
        TimeLimitsEditFieldLabel        matlab.ui.control.Label
        TimestepsEditField              matlab.ui.control.NumericEditField
        TimestepsEditFieldLabel         matlab.ui.control.Label
        SimulationSettingsLabel         matlab.ui.control.Label
        PlotSetTab                      matlab.ui.container.Tab
        RotorDistButton                 matlab.ui.control.Button
        FigureDropDown_2                matlab.ui.control.DropDown
        FigureDropDown_2Label           matlab.ui.control.Label
        BladeDistButton                 matlab.ui.control.Button
        AzimutalEDistButton             matlab.ui.control.Button
        ElementmSpinner                 matlab.ui.control.Spinner
        ElementmSpinnerLabel            matlab.ui.control.Label
        AzimutalSpinner                 matlab.ui.control.Spinner
        AzimutalLabel                   matlab.ui.control.Label
        TimesSpinner                    matlab.ui.control.Spinner
        TimesSpinnerLabel               matlab.ui.control.Label
        VariableDropDown                matlab.ui.control.DropDown
        VariableDropDownLabel           matlab.ui.control.Label
        PLOTButton                      matlab.ui.control.Button
        FigureDropDown                  matlab.ui.control.DropDown
        FigureDropDownLabel             matlab.ui.control.Label
        Variable2DropDown               matlab.ui.control.DropDown
        Variable2DropDownLabel          matlab.ui.control.Label
        Variable1DropDown               matlab.ui.control.DropDown
        Variable1DropDownLabel          matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_1                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        % APPs

        analysis2D_app % Description

        % APP settings

        simulation_data_available = false

        % AIRFOILS       

        % DEFAULT VALUES

        default_vehicle_drag_mode = true

        default_openRotor_mode = true

        default_rotor_tipLosses_mode = true
        default_rotor_no_blades = 3
        default_rotor_azimutal_points = 16

        default_blade_span = 1
        default_blade_epsilon = 0
        default_blade_chord = 0.17
        default_blade_lambda_chord = 1
        default_blade_theta = -10
        default_blade_twist_rate = 0
        default_blade_airfoil = "n0012"
        default_blade_no_points = 50
        default_blade_airfoil_tickness = 0.12


        default_sim_init_height = 100
        default_sim_payload_mass = 20
        default_sim_deploy_time = 2

        default_sim_ini_vel_x = 0
        default_sim_ini_vel_y = 0
        default_sim_ini_vel_z = 0

        default_sim_init_pitch_theta = 0
        default_sim_init_roll_phi = 0

        default_sim_ode_solver = "RK-4th"
        default_sim_aero_model = "Hybrid"
        default_sim_atmos_model = "ISA"
        default_sim_blade_integrator = "Trapezoidal"

        default_sim_time_step = 0.005
        default_sim_time_limit = Inf

        default_earth_radius = 6371000
        default_earth_mass = 5.972e+24
        default_earth_gravitational_constant = 6.674184e-11

        default_sim_res_val = 0

    end

     properties (Access = public)

        %

        airfoils_data_directory = ".\airfoils\data"

        % SIMULATION

        vehicle_drag_mode

        openRotor_mode % set sim to compute rotor dynamics

        rotor_tipLosses_mode % set sim to compute rotor tip  losses
        rotor_no_blades % no_blades
        rotor_azimutal_points

        blade_span 
        blade_epsilon
        blade_chord
        blade_lambda_chord
        blade_theta
        blade_twist_rate
        blade_airfoil
        blade_no_points 
        blade_airfoil_tickness

        sim_init_height
        sim_payload_mass
        sim_deploy_time

        sim_ini_vel_x
        sim_ini_vel_y
        sim_ini_vel_z

        sim_init_pitch_theta
        sim_init_roll_phi

        sim_ode_solver 
        sim_aero_model 
        sim_atmos_model
        sim_blade_integrator

        sim_time_step
        sim_time_limit

        earth_radius
        earth_mass
        earth_gravitational_constant

        sim_res_val

        % SIMULATION DATA

        OUTPUTS
        OUTPUT_rotor_data
        SIM
        TIME
        VEHICLE
        ROTOR        
        BLADE
        EARTH
        

    end
    
    methods (Access = public)
        
        function cmd_debbug_message(~, msg)
            fprintf(">> %s\n", msg);
        end

         function errorMessageBox(app, error_msg, error_type)
            fprintf("\n>> Error: %s\n\n", error_type);
            uialert(app.UIFigure, error_msg,error_type);
         end

        
         function loadAirfoil_list(app)
            % Specify the folder path
            folderPath = app.airfoils_data_directory; % Replace this with your folder path
            
            % Get all file names with .dat extension
            filePattern = fullfile(folderPath, '*.mat'); % Pattern to match .dat files
            files = dir(filePattern);
            
            % Extract and clean file names
            fileNames = {files.name}; % Get the full file names
            fileNames = erase(fileNames, '.mat'); % Remove .dat extension
                        
            % If you want to use these names in a dropdown menu in App Designer
            app.SelectairfoilDropDown.Items = fileNames; % Replace 'FileDropdown' with your dropdown component

         end

    end
    
    methods (Access = private)
        
        function init_app(app)
             loadAirfoil_list(app)
        end

        function init_var(app)

            app.vehicle_drag_mode = app.default_vehicle_drag_mode;
            app.openRotor_mode = app.default_openRotor_mode;

            app.rotor_tipLosses_mode = app.default_rotor_tipLosses_mode;
            app.rotor_no_blades = app.default_rotor_no_blades;
            app.rotor_azimutal_points = app.default_rotor_azimutal_points;

            app.blade_span = app.default_blade_span;
            app.blade_epsilon = app.default_blade_epsilon;
            app.blade_chord = app.default_blade_chord;
            app.blade_lambda_chord = app.default_blade_lambda_chord;
            app.blade_theta  = app.default_blade_theta;
            app.blade_twist_rate  = app.default_blade_twist_rate;
            app.blade_airfoil = app.default_blade_airfoil;
            app.blade_no_points = app.default_blade_no_points;
            app.blade_airfoil_tickness = app.default_blade_airfoil_tickness;

            app.sim_init_height = app.default_sim_init_height;
            app.sim_payload_mass = app.default_sim_payload_mass;
            app.sim_deploy_time = app.default_sim_deploy_time;

            app.sim_ini_vel_x = app.default_sim_ini_vel_x;
            app.sim_ini_vel_y = app.default_sim_ini_vel_y;
            app.sim_ini_vel_z = app.default_sim_ini_vel_z;

            app.sim_init_pitch_theta = app.default_sim_init_pitch_theta;
            app.sim_init_roll_phi = app.default_sim_init_roll_phi;


            app.sim_ode_solver = app.default_sim_ode_solver;
            app.sim_aero_model = app.default_sim_aero_model;
            app.sim_atmos_model = app.default_sim_atmos_model;
            app.sim_blade_integrator = app.default_sim_blade_integrator;

            app.sim_time_step = app.default_sim_time_step;
            app.sim_time_limit = app.default_sim_time_limit;

            app.earth_radius = app.default_earth_radius;
            app.earth_mass = app.default_earth_mass;
            app.earth_gravitational_constant = app.default_earth_gravitational_constant;

            app.sim_res_val = app.default_sim_res_val;

        end
        
        function printAppHeader(~)
            clc; % Limpa a tela
            fprintf('******************************************************\n');
            fprintf('*                       ASTRAL                       *\n');
            fprintf('*   Autorotation System for Targeted Recovery and    *\n');
            fprintf('*                   Aircraft Landing                 *\n');
            fprintf('*                                                    *\n');
            fprintf('* Version: 1.0                                       *\n');
            fprintf('* Year: 2025                                         *\n');
            fprintf('* Copyright © 2025 João Gaspar. All rights reserved. *\n');
            fprintf('******************************************************\n\n');
        end

        function printSimVarHeader(app)
            fprintf('\n******************************************************\n');
            fprintf('*                Simmulation Variables               *\n');
            fprintf('******************************************************\n\n');

            % Simulação de configurações do rotor
            fprintf('\tRotor Configuration:\n');
            fprintf('\t\tRotor Dynamics Mode: %d\n', app.openRotor_mode);
            fprintf('\t\tRotor Tip Losses Mode: %d\n', app.rotor_tipLosses_mode);
            fprintf('\t\tNumber of Rotor Blades: %d\n', app.rotor_no_blades);
            fprintf('\t\tAzimuthal Points: %d\n', app.rotor_azimutal_points);
            fprintf('\t\tDeploy Time: %.2f\n\n', app.sim_deploy_time);

            % Configurações da lâmina do rotor
            fprintf('\tBlade Configuration:\n');
            fprintf('\t\tBlade Span: %.2f m\n', app.blade_span);
            fprintf('\t\tBlade Epsilon: %.2f\n', app.blade_epsilon);
            fprintf('\t\tBlade Chord: %.2f m\n', app.blade_chord);
            fprintf('\t\tBlade Taper: %.2f \n', app.blade_lambda_chord);
            fprintf('\t\tBlade Theta: %.2f degrees\n', app.blade_theta);
            fprintf('\t\tBlade Twist Rate: %.2f\n', app.blade_twist_rate);
            fprintf('\t\tBlade Airfoil: %s\n', app.blade_airfoil);
            fprintf('\t\tNumber of Blade Points: %d\n', app.blade_no_points);
            fprintf('\t\tBlade Airfoil Thickness: %.2f%%\n\n', app.blade_airfoil_tickness);
            
            % Configurações de inicialização da simulação
            fprintf('\tSimulation Initial Conditions:\n');
            fprintf('\t\tInitial Height: %.2f m\n', app.sim_init_height);
            fprintf('\t\tPayload Mass: %.2f kg\n', app.sim_payload_mass);
            fprintf('\t\tDeployment Time: %.2f s\n', app.sim_deploy_time);
            fprintf('\t\tInitial Velocity X: %.2f m/s\n', app.sim_ini_vel_x);
            fprintf('\t\tInitial Velocity Y: %.2f m/s\n', app.sim_ini_vel_y);
            fprintf('\t\tInitial Velocity Z: %.2f m/s\n', app.sim_ini_vel_z);
            fprintf('\t\tInitial Pitch Theta: %.2f degrees\n', app.sim_init_pitch_theta);
            fprintf('\t\tInitial Roll Phi: %.2f degrees\n\n', app.sim_init_roll_phi);
            
            % Modelos de simulação
            fprintf('\tSimulation Models and Solver:\n');
            fprintf('\t\tODE Solver: %s\n', app.sim_ode_solver);
            fprintf('\t\tBlade integrator: %s\n', app.sim_blade_integrator);
            fprintf('\t\tAerodynamic Model: %s\n', app.sim_aero_model);
            fprintf('\t\tAtmospheric Model: %s\n\n', app.sim_atmos_model);
            
            % Configurações de tempo da simulação
            fprintf('\tSimulation Time Settings:\n');
            fprintf('\t\tTime Step: %.4f s\n', app.sim_time_step);
            fprintf('\t\tTime Limit: %.2f s\n\n', app.sim_time_limit);


        end
        
        function plotConfigurations(app)

            variables_fields = fieldnames(app.OUTPUTS);            
            app.Variable1DropDown.Items = variables_fields;
            app.Variable2DropDown.Items = variables_fields;

            if app.ROTOR.openRotor
                variables_fields = fieldnames(app.OUTPUT_rotor_data.data(1).blade_distribution_data);  
                app.VariableDropDown.Items = variables_fields;
    
                
                app.TimesSpinner.Step = app.TIME.dt;
                app.TimesSpinner.Limits = [app.OUTPUT_rotor_data.time(1) app.OUTPUT_rotor_data.time(end)];
                app.TimesSpinner.LowerLimitInclusive = 'on';
                app.TimesSpinner.UpperLimitInclusive = 'on';
                app.TimesSpinner.Value = app.OUTPUT_rotor_data.time(1);
    
                
                app.AzimutalSpinner.Step = abs(app.ROTOR.azimutal_positions(1) - app.ROTOR.azimutal_positions(2));
                app.AzimutalSpinner.Limits = [app.ROTOR.azimutal_positions(1) app.ROTOR.azimutal_positions(end)];
                app.AzimutalSpinner.LowerLimitInclusive = 'on';
                app.AzimutalSpinner.UpperLimitInclusive = 'on';
                app.AzimutalSpinner.Value = app.ROTOR.azimutal_positions(1);
    
                
                app.ElementmSpinner.Step = abs(app.BLADE.pos_sec(2,1) - app.BLADE.pos_sec(2,2));
                app.ElementmSpinner.Limits = [app.BLADE.pos_sec(2,1) app.BLADE.pos_sec(2,end)];
                app.ElementmSpinner.LowerLimitInclusive ='on';
                app.ElementmSpinner.UpperLimitInclusive ='on';
                app.ElementmSpinner.Value = app.BLADE.pos_sec(2,1);
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            printAppHeader(app)

            init_var(app)
            init_app(app)

            loadAirfoil_list(app)
                       
        end

        % Button pushed function: DAEROButton
        function DAEROButtonPushed(app, event)
            
            app.DAEROButton.Enable = 'off';
            app.analysis2D_app = analysis2D(app);

        end

        % Button pushed function: BLADEPLOTButton
        function BLADEPLOTButtonPushed(app, event)
            
            plot_blade_3D(app.blade_airfoil, app.blade_no_points, app.blade_span, app.blade_epsilon, app.blade_chord, app.blade_lambda_chord, app.blade_twist_rate, app.blade_theta)
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            clc

            if isfile("log.txt")
                delete("log.txt");
            end
            
            diary("log.txt")              
            
            app.simulation_data_available = false;

            printSimVarHeader(app)

            % Initilize simulation Data
            [app.SIM, app.TIME, app.VEHICLE, app.ROTOR, app.BLADE, app.EARTH] = init_sim_inputs(app);

            app.simulation_data_available = true;

            % Run simulation
            % 
                        out = run_simulation(app.SIM, app.TIME, app.VEHICLE, app.ROTOR, app.BLADE, app.EARTH);
            
            diary off;

            save("sim.mat", "out")
            
            app.OUTPUT_rotor_data = out.rotor_data;
            app.OUTPUTS = out;
         
            % Update Dropdown menus for plots

            if isfield(app.OUTPUTS, "rotor_data")
                app.OUTPUTS = rmfield(app.OUTPUTS, "rotor_data");


            end

            plotConfigurations(app)

            % configurações dos botões em caso de abertura ou não do rotor

            app.AzimutalEDistButton.Enable = app.ROTOR.openRotor;
            app.BladeDistButton.Enable = app.ROTOR.openRotor;
            app.RotorDistButton.Enable = app.ROTOR.openRotor;

                       
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            
            if ~app.simulation_data_available
                errorMessageBox(app, "Simulation data is not available: first run a simulation", "Simulation Error")
                return;
            end            
            
            x_values = app.OUTPUTS.(app.Variable1DropDown.Value);
            y_values = app.OUTPUTS.(app.Variable2DropDown.Value);


            if strcmp(app.FigureDropDown.Value, "Plot 1")
                plot_UI = app.UIAxes_1;
            elseif strcmp(app.FigureDropDown.Value, "Plot 2")
                plot_UI = app.UIAxes_2;
            elseif strcmp(app.FigureDropDown.Value, "Plot 3")
                plot_UI = app.UIAxes_3;
            else
                errorMessageBox(app, "Unexpected error", "Plot error")
                return;
            end

            save_plot_position = plot_UI.Position;

            plot(plot_UI, x_values, y_values)
            legend(plot_UI, "X", "Y", "Z", "Location", "eastoutside")
            
            plot_UI.Position = save_plot_position;


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close all;
            delete(app.analysis2D_app)
            delete(app)
            clc
            fprintf(">> ASTRAL app as terminated with success\n\n")

        end

        % Value changed function: OpenRotorCheckBox
        function OpenRotorCheckBoxValueChanged(app, event)
            app.openRotor_mode = app.OpenRotorCheckBox.Value;

            if  app.openRotor_mode
                mode = 'On';
            else
                mode = 'Off';
            end
            cmd_debbug_message(app, "[SIM settings] Rotor mode = " + mode)
            
        end

        % Value changed function: TipLossesCheckBox
        function TipLossesCheckBoxValueChanged(app, event)
            app.rotor_tipLosses_mode = app.TipLossesCheckBox.Value;

            if  app.rotor_tipLosses_mode
                mode = 'On';
            else
                mode = 'Off';
            end
            cmd_debbug_message(app, "[SIM settings] Tip losses mode = " + mode)
            
        end

        % Value changed function: NoofBladesSpinner
        function NoofBladesSpinnerValueChanged(app, event)
            value = app.NoofBladesSpinner.Value;

            if value <= 1
                errorMessageBox(app, "Invalid value for no. of blade: should be 2 or hiher number", "Input Error")
                app.NoofBladesSpinner.Value = app.default_rotor_no_blades;
            else
                app.rotor_no_blades = value;
                cmd_debbug_message(app, "[SIM settings] Number of blades = " + num2str(app.rotor_no_blades))
            end
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            
            if value < 0
                errorMessageBox(app, "Invalid value for blade span: should be higher than zero", "Input Error")
                app.bEditField.Value = app.default_blade_span;
            elseif value < app.blade_epsilon
                
                errorMessageBox(app, "Invalid value for blade span: should be higher than blade-root distance", "Input Error")
               
                if app.blade_epsilon > 0
                    app.bEditField.Value = app.blade_epsilon * 1.25;
                    app.blade_span = app.blade_epsilon * 1.25; 
                else
                    app.bEditField.Value = app.default_blade_span;
                    app.blade_span = app.default_blade_span;
                end

            else
                app.blade_span = value;
                 cmd_debbug_message(app, "[SIM settings] Blade span = " + num2str(app.blade_span) + " m")
            end

        end

        % Value changed function: cEditField
        function cEditFieldValueChanged(app, event)
            value = app.cEditField.Value;

            if value < app.blade_span * 0.01
                errorMessageBox(app, "Invalid value for chord: value should be higher than 1% of blade length", "Input Error")
                app.cEditField.Value = app.default_blade_chord;
            else
                app.blade_chord = value;
                 cmd_debbug_message(app, "[SIM settings] Blade span = " + num2str(app.blade_chord) + " m")
            end
            
        end

        % Value changed function: epsilontextEditField
        function epsilontextEditFieldValueChanged(app, event)
            value = app.epsilontextEditField.Value;

            if value < 0
                errorMessageBox(app, "Invalid value for Blade-root distance: should higher than 0", "Input Error")
                app.bEditField.Value = app.default_blade_epsilon;

            elseif value > app.blade_span
                errorMessageBox(app, "Invalid value blade span: should be lower than blade span", "Input Error")
                app.epsilontextEditField.Value = app.blade_span * 0.75;
                app.blade_epsilon = app.blade_span * 0.75;
            else
                app.blade_epsilon = value;
                cmd_debbug_message(app, "[SIM settings] Blade-root distance = " + num2str(app.blade_epsilon) + " m")
            end
            
        end

        % Value changed function: lambda_cEditField
        function lambda_cEditFieldValueChanged(app, event)
            value = app.lambda_cEditField.Value;

            if value < 0.05
                errorMessageBox(app, "Invalid value for Taper Index: should higher at leat 5% of chord at root", "Input Error")
                app.blade_lambda_chord = app.default_blade_lambda_chord;

            elseif value > 1
                errorMessageBox(app, "Invalid value for Taper Index: max value is 1", "Input Error")
                app.lambda_cEditField.Value = 1;
                app.blade_lambda_chord = 1;
            else
                app.blade_lambda_chord = value;
                cmd_debbug_message(app, "[SIM settings] Taper index = " + num2str(app.blade_epsilon))
            end
            
        end

        % Value changed function: thetatextEditField
        function thetatextEditFieldValueChanged(app, event)
            value = app.thetatextEditField.Value;
            
            if value < -180 || value > 180
                errorMessageBox(app, "Invalid value blade span: should be lower than blade span", "Input Error")
                app.thetatextEditField.Value = app.blade_span * 0.75;
                app.blade_theta  = app.default_blade_theta;
            else
                app.blade_theta = value;
                cmd_debbug_message(app, "[SIM settings] Blade root theta = " + num2str(app.blade_theta) + " º")
            end
    

        end

        % Value changed function: fracmathrmdthetamathrmdyEditField
        function fracmathrmdthetamathrmdyEditFieldValueChanged(app, event)
            value = app.fracmathrmdthetamathrmdyEditField.Value;

            app.blade_twist_rate = value;
            cmd_debbug_message(app, "[SIM settings] Rate of twist = " + num2str(app.blade_twist_rate) + " º/m")
        
        end

        % Value changed function: SelectairfoilDropDown
        function SelectairfoilDropDownValueChanged(app, event)
            
            app.blade_airfoil = app.SelectairfoilDropDown.Value;


            cmd_debbug_message(app, "[SIM settings] Airfoil = " + num2str( app.blade_airfoil))
            
        end

        % Value changed function: DeltayNoPointsEditField
        function DeltayNoPointsEditFieldValueChanged(app, event)
            value = app.DeltayNoPointsEditField.Value;
            
            if value < 2
                errorMessageBox(app, "Invalid value for no. of blade discretization: should be higher than 2", "Input Error")
                app.blade_no_points = app.default_blade_no_points;
            else
                app.blade_no_points = value;
                cmd_debbug_message(app, "[SIM settings] No. of blade discretization = " + num2str(app.blade_no_points))

            end
        end

        % Value changed function: HeightmEditField
        function HeightmEditFieldValueChanged(app, event)
            value = app.HeightmEditField.Value;

            if value < 0
                errorMessageBox(app, "Invalid value for initial height: should be higher than 0", "Input Error")
                app.HeightmEditField.Value = app.default_sim_init_height;
                app.sim_init_height = app.default_sim_init_height;
            else
                app.sim_init_height = value;
                cmd_debbug_message(app, "[SIM settings] Initial Height = " + num2str(app.sim_init_height) + " m")
            end
            
        end

        % Value changed function: PayloadMasskgEditField
        function PayloadMasskgEditFieldValueChanged(app, event)
            value = app.PayloadMasskgEditField.Value;

             if value < 0
                errorMessageBox(app, "Invalid value for payload mass: should be higher than 0", "Input Error")
                app.PayloadMasskgEditField.Value = app.default_sim_payload_mass;
                app.sim_payload_mass = app.default_sim_payload_mass;
            else
                app.sim_payload_mass = value;
                cmd_debbug_message(app, "[SIM settings] Payload Mass = " + num2str(app.sim_payload_mass) + " kg")
            end
            
        end

        % Value changed function: DeploymenttimesEditField
        function DeploymenttimesEditFieldValueChanged(app, event)
            value = app.DeploymenttimesEditField.Value;
            
            if value < 0
                errorMessageBox(app, "Invalid value for rotor deploy time: should be higher than 0", "Input Error")
                app.DeploymenttimesEditField.Value = app.default_sim_deploy_time;
                app.sim_deploy_time = app.default_sim_deploy_time;
            else
                app.sim_deploy_time = value;
                cmd_debbug_message(app, "[SIM settings] Deploy time = " + num2str(app.sim_deploy_time) + " s")
            end
        end

        % Value changed function: VxEditField
        function VxEditFieldValueChanged(app, event)
            value = app.VxEditField.Value;
            
            app.sim_ini_vel_x = value;
            cmd_debbug_message(app, "[SIM settings] Init Velocity - X = " + num2str(app.sim_ini_vel_x) + " m/s")

        end

        % Value changed function: VyEditField
        function VyEditFieldValueChanged(app, event)
            value = app.VyEditField.Value;
            
            app.sim_ini_vel_y = value;
            cmd_debbug_message(app, "[SIM settings] Init Velocity - Y = " + num2str(app.sim_ini_vel_y) + " m/s")

        end

        % Value changed function: VzEditField
        function VzEditFieldValueChanged(app, event)
            value = app.VzEditField.Value;

            app.sim_ini_vel_z = value;
            cmd_debbug_message(app, "[SIM settings] Init Velocity - Z = " + num2str(app.sim_ini_vel_z) + " m/s")

        end

        % Value changed function: phiEditField
        function phiEditFieldValueChanged(app, event)
            value = app.phiEditField.Value;
            
            if value < -180 || value > 180
                errorMessageBox(app, "Invalid value for vehicle roll angle: should be in [-180º, 180º]", "Input Error")
                app.phiEditField.Value = app.default_sim_init_roll_phi;
                app.sim_init_roll_phi = app.default_sim_init_roll_phi;
            else
                app.sim_init_roll_phi = value;
                cmd_debbug_message(app, "[SIM settings] Roll init value = " + num2str(app.sim_init_roll_phi) + "º")
            end
        end

        % Value changed function: thetaEditField
        function thetaEditFieldValueChanged(app, event)
            value = app.thetaEditField.Value;
            
            if value < -180 || value > 180
                errorMessageBox(app, "Invalid value for vehicle roll angle: should be in [-180º, 180º]", "Input Error")
                app.thetaEditField.Value = app.default_sim_init_pitch_theta;
                app.sim_init_pitch_theta = app.default_sim_init_pitch_theta;
            else
                app.sim_init_pitch_theta = value;
                cmd_debbug_message(app, "[SIM settings] Roll init value = " + num2str(app.sim_init_pitch_theta) + "º")
            end
        end

        % Value changed function: DeltaPsitextNoPointsEditField
        function DeltaPsitextNoPointsEditFieldValueChanged(app, event)
            value = app.DeltaPsitextNoPointsEditField.Value;
            
            if value < 2
                errorMessageBox(app, "Invalid value for no. of blade discretization: should be higher than 2", "Input Error")
                app.rotor_azimutal_points = app.default_rotor_azimutal_points;
                app.DeltaPsitextNoPointsEditField.Value = app.default_rotor_azimutal_points;
            else
                app.rotor_azimutal_points = value;
                cmd_debbug_message(app, "[SIM settings] No. of rotor azimutal discretization = " + num2str(app.rotor_azimutal_points))

            end
            
        end

        % Value changed function: ODESolverDropDown
        function ODESolverDropDownValueChanged(app, event)
            value = app.ODESolverDropDown.Value;
            
            app.sim_ode_solver = value;
            cmd_debbug_message(app, "[SIM settings] Solver = " + app.sim_ode_solver)

        end

        % Value changed function: AerodynamicModelDropDown
        function AerodynamicModelDropDownValueChanged(app, event)
            value = app.AerodynamicModelDropDown.Value;
            
             app.sim_aero_model = value;
             cmd_debbug_message(app, "[SIM settings] 2D Aerodynamic model = " + app.sim_aero_model)

        end

        % Value changed function: AtmosphericModelDropDown
        function AtmosphericModelDropDownValueChanged(app, event)
            value = app.AtmosphericModelDropDown.Value;
            
            app.sim_atmos_model = value;
            cmd_debbug_message(app, "[SIM settings] Atmospheric model = " + app.sim_atmos_model)

        end

        % Value changed function: TimestepsEditField
        function TimestepsEditFieldValueChanged(app, event)
            value = app.TimestepsEditField.Value;

            if value <= 0
                errorMessageBox(app, "Invalid value for time step: should be higher than 0", "Input Error")
                app.TimestepsEditField.Value = app.default_sim_time_step;
                app.sim_time_step = app.default_sim_time_step;
            else
                app.sim_time_step = value;
                cmd_debbug_message(app, "[SIM settings] Time step = " + num2str(app.sim_time_step) + " s")
            end
            
        end

        % Value changed function: TimeLimitsEditField
        function TimeLimitsEditFieldValueChanged(app, event)
            value = app.TimeLimitsEditField.Value;

            if value <= 0
                errorMessageBox(app, "Invalid value for time limit: should be higher than 0", "Input Error")
                app.TimeLimitsEditField.Value = app.default_sim_time_limit;
                app.sim_time_limit = app.default_sim_time_limit;
            else
                app.sim_time_limit = value;
                cmd_debbug_message(app, "[SIM settings] Time step = " + num2str(app.sim_time_limit) + " s")
            end
            
        end

        % Callback function
        function tcEditFieldValueChanged(app, event)
            value = app.tcEditField.Value;

            if value < 1 || value > 100
                errorMessageBox(app, "Invalid value airfoil tickness: should be in [1%, 100%]", "Input Error")
                app.tcEditField.Value = app.default_blade_airfoil_tickness;
                app.blade_airfoil_tickness = app.default_blade_airfoil_tickness;
            else
                app.blade_airfoil_tickness = value;
                cmd_debbug_message(app, "[SIM settings] Airfoil tickness = " + num2str(app.blade_airfoil_tickness) + "%")
            end
            
        end

        % Value changed function: ResidualValueEditField
        function ResidualValueEditFieldValueChanged(app, event)
            value = app.ResidualValueEditField.Value;
            app.sim_res_val = value;
            cmd_debbug_message(app, "[SIM settings] Residual Values = " + app.sim_res_val)
        end

        % Value changed function: VehicleDragCheckBox
        function VehicleDragCheckBoxValueChanged(app, event)
            app.vehicle_drag_mode = app.VehicleDragCheckBox.Value;
            
            if  app.vehicle_drag_mode
                mode = 'On';
            else
                mode = 'Off';
            end
            cmd_debbug_message(app, "[SIM settings] Vehicle drag mode = " + mode)
        end

        % Value changed function: Variable1DropDown
        function Variable1DropDownValueChanged(app, event)
            value = app.Variable1DropDown.Value;
            
        end

        % Value changed function: Variable2DropDown
        function Variable2DropDownValueChanged(app, event)
            value = app.Variable2DropDown.Value;

        end

        % Value changed function: FigureDropDown
        function FigureDropDownValueChanged(app, event)
            value = app.FigureDropDown.Value;
            
        end

        % Menu selected function: GitRepMenu
        function GitRepMenuSelected(app, event)
            link = 'https://github.com/joaogaspar00/ASTRAL';
            % Abre o link no navegador padrão
            web(link, '-browser');
        end

        % Value changed function: BladesIntegrationDropDown
        function BladesIntegrationDropDownValueChanged(app, event)
            value = app.BladesIntegrationDropDown.Value;
            
            app.sim_blade_integrator = value;
            cmd_debbug_message(app, "[SIM settings] Blade's integration rule = " + app.sim_blade_integrator)
        end

        % Button pushed function: AzimutalEDistButton
        function AzimutalEDistButtonPushed(app, event)
            if ~app.simulation_data_available
                errorMessageBox(app, "Simulation data is not available: first run a simulation", "Simulation Error")
                return;
            end

            x_values = app.ROTOR.azimutal_positions;

            field_plot = app.VariableDropDown.Value;

            time_value = app.TimesSpinner.Value;
            [~, time_id] = min(abs(app.OUTPUT_rotor_data.time - time_value));

            element_value = app.ElementmSpinner.Value;
            [~, element_id] = min(abs(app.BLADE.pos_sec(2,:) - element_value));

            y_values = zeros(1, length(app.ROTOR.azimutal_positions));

            for azimute_id=1:length(app.ROTOR.azimutal_positions)
                y_values(azimute_id) = app.OUTPUT_rotor_data.data(time_id).blade_distribution_data(azimute_id).(field_plot)(element_id);
            end
            
            if strcmp(app.FigureDropDown_2.Value, "Plot 1")
                plot_UI = app.UIAxes_1;
            elseif strcmp(app.FigureDropDown_2.Value, "Plot 2")
                plot_UI = app.UIAxes_2;
            elseif strcmp(app.FigureDropDown_2.Value, "Plot 3")
                plot_UI = app.UIAxes_3;
            else
                errorMessageBox(app, "Unexpected error", "Plot error")
                return;
            end

            save_plot_position = plot_UI.Position;

            plot(plot_UI, x_values, y_values)            
            plot_UI.Position = save_plot_position;


        end

        % Button pushed function: BladeDistButton
        function BladeDistButtonPushed(app, event)
            if ~app.simulation_data_available
                errorMessageBox(app, "Simulation data is not available: first run a simulation", "Simulation Error")
                return;
            end

            x_values = app.BLADE.pos_sec(2,:);

            field_plot = app.VariableDropDown.Value;

            time_value = app.TimesSpinner.Value;
            [~, time_id] = min(abs(app.OUTPUT_rotor_data.time - time_value));

            azimute_value = app.AzimutalSpinner.Value;
            [~, azimute_id] = min(abs(app.ROTOR.azimutal_positions - azimute_value));

            y_values = app.OUTPUT_rotor_data.data(time_id).blade_distribution_data(azimute_id).(field_plot);
            
            
            if strcmp(app.FigureDropDown_2.Value, "Plot 1")
                plot_UI = app.UIAxes_1;
            elseif strcmp(app.FigureDropDown_2.Value, "Plot 2")
                plot_UI = app.UIAxes_2;
            elseif strcmp(app.FigureDropDown_2.Value, "Plot 3")
                plot_UI = app.UIAxes_3;
            else
                errorMessageBox(app, "Unexpected error", "Plot error")
                return;
            end

            save_plot_position = plot_UI.Position;

            plot(plot_UI, x_values, y_values)            
            plot_UI.Position = save_plot_position;        

        end

        % Button pushed function: RotorDistButton
        function RotorDistButtonPushed(app, event)
            if ~app.simulation_data_available
                errorMessageBox(app, "Simulation data is not available: first run a simulation", "Simulation Error")
                return;
            end

            field_plot = app.VariableDropDown.Value;

            time_value = app.TimesSpinner.Value;
            [~, time_id] = min(abs(app.OUTPUT_rotor_data.time - time_value));

            for p=1:length(app.ROTOR.azimutal_positions)
                values(p, :) = app.OUTPUT_rotor_data.data(time_id).blade_distribution_data(p).(field_plot);
            end

            azimutes = app.ROTOR.azimutal_positions;
            rho = app.BLADE.pos_sec(2, :);
            
            polarPlot(azimutes, rho, values, "", "", "")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InfoMenu = uimenu(app.UIFigure);
            app.InfoMenu.Text = 'Info';
            app.GitRepMenu = uimenu(app.InfoMenu);
            app.GitRepMenu.MenuSelectedFcn = createCallbackFcn(app, @GitRepMenuSelected, true);
            app.GitRepMenu.Text = 'Git Rep';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RotorTab = uitab(app.TabGroup);
            app.RotorTab.Title = 'Rotor';
            app.OpenRotorCheckBox = uicheckbox(app.RotorTab);
            app.OpenRotorCheckBox.ValueChangedFcn = createCallbackFcn(app, @OpenRotorCheckBoxValueChanged, true);
            app.OpenRotorCheckBox.Text = 'Open Rotor';
            app.OpenRotorCheckBox.Value = true;
            app.TipLossesCheckBox = uicheckbox(app.RotorTab);
            app.TipLossesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TipLossesCheckBoxValueChanged, true);
            app.TipLossesCheckBox.Text = 'Tip Losses';
            app.TipLossesCheckBox.Value = true;
            app.RotorLabel = uilabel(app.RotorTab);
            app.RotorLabel.Text = 'Rotor';
            app.BladeLabel = uilabel(app.RotorTab);
            app.BladeLabel.Text = 'Blade';
            app.bLabel = uilabel(app.RotorTab);
            app.bLabel.Interpreter = 'latex';
            app.bLabel.Text = 'b =';
            app.bEditField = uieditfield(app.RotorTab, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Tooltip = {'Blade Span [meters]'};
            app.bEditField.Value = 1;
            app.cLabel = uilabel(app.RotorTab);
            app.cLabel.Interpreter = 'latex';
            app.cLabel.Text = 'c = ';
            app.cEditField = uieditfield(app.RotorTab, 'numeric');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @cEditFieldValueChanged, true);
            app.cEditField.Value = 0.17;
            app.thetatextEditFieldLabel = uilabel(app.RotorTab);
            app.thetatextEditFieldLabel.Interpreter = 'latex';
            app.thetatextEditFieldLabel.Text = '\theta \text{ } = ';
            app.thetatextEditField = uieditfield(app.RotorTab, 'numeric');
            app.thetatextEditField.ValueChangedFcn = createCallbackFcn(app, @thetatextEditFieldValueChanged, true);
            app.thetatextEditField.Value = -10;
            app.epsilonLabel = uilabel(app.RotorTab);
            app.epsilonLabel.Interpreter = 'latex';
            app.epsilonLabel.Text = '\epsilon \text{ } = ';
            app.epsilontextEditField = uieditfield(app.RotorTab, 'numeric');
            app.epsilontextEditField.ValueChangedFcn = createCallbackFcn(app, @epsilontextEditFieldValueChanged, true);
            app.fracmathrmdthetamathrmdyLabel = uilabel(app.RotorTab);
            app.fracmathrmdthetamathrmdyLabel.Interpreter = 'latex';
            app.fracmathrmdthetamathrmdyLabel.Text = '\frac{\mathrm{d}\theta}{\mathrm{d}y} = ';
            app.fracmathrmdthetamathrmdyEditField = uieditfield(app.RotorTab, 'numeric');
            app.fracmathrmdthetamathrmdyEditField.ValueChangedFcn = createCallbackFcn(app, @fracmathrmdthetamathrmdyEditFieldValueChanged, true);
            app.mLabel = uilabel(app.RotorTab);
            app.mLabel.Text = '[m]';
            app.mLabel_2 = uilabel(app.RotorTab);
            app.mLabel_2.Text = '[m]';
            app.mLabel_3 = uilabel(app.RotorTab);
            app.mLabel_3.Text = '[m]';
            app.Label = uilabel(app.RotorTab);
            app.Label.Text = '[º]';
            app.mLabel_4 = uilabel(app.RotorTab);
            app.mLabel_4.Text = '[º/m]';
            app.NoofBladesSpinnerLabel = uilabel(app.RotorTab);
            app.NoofBladesSpinnerLabel.Text = 'No. of Blades';
            app.NoofBladesSpinner = uispinner(app.RotorTab);
            app.NoofBladesSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofBladesSpinnerValueChanged, true);
            app.NoofBladesSpinner.Value = 3;
            app.AirfoilLabel = uilabel(app.RotorTab);
            app.AirfoilLabel.Text = 'Airfoil';
            app.SelectairfoilDropDownLabel = uilabel(app.RotorTab);
            app.SelectairfoilDropDownLabel.Text = 'Select airfoil';
            app.SelectairfoilDropDown = uidropdown(app.RotorTab);
            app.SelectairfoilDropDown.Items = {'n0012'};
            app.SelectairfoilDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectairfoilDropDownValueChanged, true);
            app.SelectairfoilDropDown.Value = 'n0012';
            app.DeltayNoPointsEditFieldLabel = uilabel(app.RotorTab);
            app.DeltayNoPointsEditFieldLabel.Interpreter = 'latex';
            app.DeltayNoPointsEditFieldLabel.Text = '\Delta y No. Points';
            app.DeltayNoPointsEditField = uieditfield(app.RotorTab, 'numeric');
            app.DeltayNoPointsEditField.ValueChangedFcn = createCallbackFcn(app, @DeltayNoPointsEditFieldValueChanged, true);
            app.DeltayNoPointsEditField.Value = 50;
            app.DeltaPsitextNoPointsLabel = uilabel(app.RotorTab);
            app.DeltaPsitextNoPointsLabel.Interpreter = 'latex';
            app.DeltaPsitextNoPointsLabel.Text = '\Delta \Psi  \text{ No. Points}';
            app.DeltaPsitextNoPointsEditField = uieditfield(app.RotorTab, 'numeric');
            app.DeltaPsitextNoPointsEditField.ValueChangedFcn = createCallbackFcn(app, @DeltaPsitextNoPointsEditFieldValueChanged, true);
            app.DeltaPsitextNoPointsEditField.Value = 16;
            app.alpha_cLabel = uilabel(app.RotorTab);
            app.alpha_cLabel.Interpreter = 'latex';
            app.alpha_cLabel.Text = '\lambda_c = ';
            app.lambda_cEditField = uieditfield(app.RotorTab, 'numeric');
            app.lambda_cEditField.ValueChangedFcn = createCallbackFcn(app, @lambda_cEditFieldValueChanged, true);
            app.lambda_cEditField.Value = 1;
            app.VehicleTab = uitab(app.TabGroup);
            app.VehicleTab.Title = 'Vehicle';
            app.INITIALCONDITIONSLabel = uilabel(app.VehicleTab);
            app.INITIALCONDITIONSLabel.Text = 'INITIAL CONDITIONS';
            app.VEICHLELabel = uilabel(app.VehicleTab);
            app.VEICHLELabel.Text = 'VEICHLE';
            app.RotorDeploymentLabel = uilabel(app.VehicleTab);
            app.RotorDeploymentLabel.Text = 'Rotor Deployment';
            app.VehicleDragCheckBox = uicheckbox(app.VehicleTab);
            app.VehicleDragCheckBox.ValueChangedFcn = createCallbackFcn(app, @VehicleDragCheckBoxValueChanged, true);
            app.VehicleDragCheckBox.Text = 'Vehicle Drag';
            app.VehicleDragCheckBox.Value = true;
            app.HeightmEditFieldLabel = uilabel(app.VehicleTab);
            app.HeightmEditFieldLabel.Text = 'Height [m]';
            app.HeightmEditField = uieditfield(app.VehicleTab, 'numeric');
            app.HeightmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightmEditFieldValueChanged, true);
            app.HeightmEditField.Value = 100;
            app.PayloadMasskgEditFieldLabel = uilabel(app.VehicleTab);
            app.PayloadMasskgEditFieldLabel.Text = 'Payload Mass  [kg]';
            app.PayloadMasskgEditField = uieditfield(app.VehicleTab, 'numeric');
            app.PayloadMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @PayloadMasskgEditFieldValueChanged, true);
            app.PayloadMasskgEditField.Value = 20;
            app.DeploymenttimesEditFieldLabel = uilabel(app.VehicleTab);
            app.DeploymenttimesEditFieldLabel.Text = 'Deployment time [s]';
            app.DeploymenttimesEditField = uieditfield(app.VehicleTab, 'numeric');
            app.DeploymenttimesEditField.ValueChangedFcn = createCallbackFcn(app, @DeploymenttimesEditFieldValueChanged, true);
            app.DeploymenttimesEditField.Value = 2;
            app.VelocitymsLabel = uilabel(app.VehicleTab);
            app.VelocitymsLabel.Text = 'Velocity [m/s]';
            app.AttitudeLabel = uilabel(app.VehicleTab);
            app.AttitudeLabel.Text = 'Attitude [º]';
            app.RollLabel = uilabel(app.VehicleTab);
            app.RollLabel.Text = 'Roll';
            app.PitchLabel = uilabel(app.VehicleTab);
            app.PitchLabel.Text = 'Pitch';
            app.VyEditFieldLabel = uilabel(app.VehicleTab);
            app.VyEditFieldLabel.Text = 'Vy';
            app.VyEditField = uieditfield(app.VehicleTab, 'numeric');
            app.VyEditField.ValueChangedFcn = createCallbackFcn(app, @VyEditFieldValueChanged, true);
            app.VzEditFieldLabel = uilabel(app.VehicleTab);
            app.VzEditFieldLabel.Text = 'Vz';
            app.VzEditField = uieditfield(app.VehicleTab, 'numeric');
            app.VzEditField.ValueChangedFcn = createCallbackFcn(app, @VzEditFieldValueChanged, true);
            app.thetaEditFieldLabel = uilabel(app.VehicleTab);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.VehicleTab, 'numeric');
            app.thetaEditField.ValueChangedFcn = createCallbackFcn(app, @thetaEditFieldValueChanged, true);
            app.phiEditFieldLabel = uilabel(app.VehicleTab);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.VehicleTab, 'numeric');
            app.phiEditField.ValueChangedFcn = createCallbackFcn(app, @phiEditFieldValueChanged, true);
            app.VxEditFieldLabel = uilabel(app.VehicleTab);
            app.VxEditFieldLabel.Text = 'Vx';
            app.VxEditField = uieditfield(app.VehicleTab, 'numeric');
            app.VxEditField.ValueChangedFcn = createCallbackFcn(app, @VxEditFieldValueChanged, true);
            app.SimTab = uitab(app.TabGroup);
            app.SimTab.Title = 'Sim';
            app.SimulationSettingsLabel = uilabel(app.SimTab);
            app.SimulationSettingsLabel.Text = 'Simulation Settings';
            app.TimestepsEditFieldLabel = uilabel(app.SimTab);
            app.TimestepsEditFieldLabel.Text = 'Time step [s]';
            app.TimestepsEditField = uieditfield(app.SimTab, 'numeric');
            app.TimestepsEditField.ValueChangedFcn = createCallbackFcn(app, @TimestepsEditFieldValueChanged, true);
            app.TimestepsEditField.Value = 0.005;
            app.TimeLimitsEditFieldLabel = uilabel(app.SimTab);
            app.TimeLimitsEditFieldLabel.Text = 'Time Limit [s]';
            app.TimeLimitsEditField = uieditfield(app.SimTab, 'numeric');
            app.TimeLimitsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLimitsEditFieldValueChanged, true);
            app.TimeLimitsEditField.Value = Inf;
            app.ODESolverDropDownLabel = uilabel(app.SimTab);
            app.ODESolverDropDownLabel.Text = 'ODE Solver';
            app.ODESolverDropDown = uidropdown(app.SimTab);
            app.ODESolverDropDown.Items = {'RK-4th'};
            app.ODESolverDropDown.ValueChangedFcn = createCallbackFcn(app, @ODESolverDropDownValueChanged, true);
            app.ODESolverDropDown.Value = 'RK-4th';
            app.ResidualValueEditFieldLabel = uilabel(app.SimTab);
            app.ResidualValueEditFieldLabel.Text = 'Residual Value';
            app.ResidualValueEditField = uieditfield(app.SimTab, 'numeric');
            app.ResidualValueEditField.ValueChangedFcn = createCallbackFcn(app, @ResidualValueEditFieldValueChanged, true);
            app.AtmosphericModelDropDownLabel = uilabel(app.SimTab);
            app.AtmosphericModelDropDownLabel.Text = 'Atmospheric Model';
            app.AtmosphericModelDropDown = uidropdown(app.SimTab);
            app.AtmosphericModelDropDown.Items = {'ISA', 'EAM', 'NRLMSISE-00'};
            app.AtmosphericModelDropDown.ValueChangedFcn = createCallbackFcn(app, @AtmosphericModelDropDownValueChanged, true);
            app.AtmosphericModelDropDown.Value = 'ISA';
            app.AerodynamicModelDropDown_2Label = uilabel(app.SimTab);
            app.AerodynamicModelDropDown_2Label.Text = 'Aerodynamic Model';
            app.AerodynamicModelDropDown = uidropdown(app.SimTab);
            app.AerodynamicModelDropDown.Items = {'Hybrid', 'XFOIL', 'NeuralFoil'};
            app.AerodynamicModelDropDown.ValueChangedFcn = createCallbackFcn(app, @AerodynamicModelDropDownValueChanged, true);
            app.AerodynamicModelDropDown.Value = 'Hybrid';
            app.SolverModelsLabel = uilabel(app.SimTab);
            app.SolverModelsLabel.Text = ' Solver & Models';
            app.EarthRadiusmEditFieldLabel = uilabel(app.SimTab);
            app.EarthRadiusmEditFieldLabel.Text = 'Earth Radius [m]';
            app.EarthRadiusmEditField = uieditfield(app.SimTab, 'numeric');
            app.EarthRadiusmEditField.Editable = 'off';
            app.EarthRadiusmEditField.Value = 6371000;
            app.EarthMasskgEditFieldLabel = uilabel(app.SimTab);
            app.EarthMasskgEditFieldLabel.Text = 'Earth Mass [kg]';
            app.EarthMasskgEditField = uieditfield(app.SimTab, 'numeric');
            app.EarthMasskgEditField.Editable = 'off';
            app.EarthMasskgEditField.Value = 5.972e+24;
            app.GravitationalConstantm3kg1s2EditFieldLabel = uilabel(app.SimTab);
            app.GravitationalConstantm3kg1s2EditFieldLabel.Text = {'Gravitational Constant'; '[m^3 kg^(−1) s^(−2)]'};
            app.GravitationalConstantm3kg1s2EditField = uieditfield(app.SimTab, 'numeric');
            app.GravitationalConstantm3kg1s2EditField.Editable = 'off';
            app.GravitationalConstantm3kg1s2EditField.Value = 6.674184e-11;
            app.BladesIntegrationDropDownLabel = uilabel(app.SimTab);
            app.BladesIntegrationDropDownLabel.Text = 'Blade''s Integration';
            app.BladesIntegrationDropDown = uidropdown(app.SimTab);
            app.BladesIntegrationDropDown.Items = {'Rectangle', 'Trapezoidal', 'Simpson', 'Boole'};
            app.BladesIntegrationDropDown.ValueChangedFcn = createCallbackFcn(app, @BladesIntegrationDropDownValueChanged, true);
            app.BladesIntegrationDropDown.Value = 'Rectangle';
            app.PlotSetTab = uitab(app.TabGroup);
            app.PlotSetTab.Title = 'Plot Set.';
            app.Variable1DropDownLabel = uilabel(app.PlotSetTab);
            app.Variable1DropDownLabel.Text = 'Variable 1';
            app.Variable1DropDown = uidropdown(app.PlotSetTab);
            app.Variable1DropDown.Items = {};
            app.Variable1DropDown.ValueChangedFcn = createCallbackFcn(app, @Variable1DropDownValueChanged, true);
            app.Variable1DropDown.Value = {};
            app.Variable2DropDownLabel = uilabel(app.PlotSetTab);
            app.Variable2DropDownLabel.Text = 'Variable  2';
            app.Variable2DropDown = uidropdown(app.PlotSetTab);
            app.Variable2DropDown.Items = {''};
            app.Variable2DropDown.ValueChangedFcn = createCallbackFcn(app, @Variable2DropDownValueChanged, true);
            app.Variable2DropDown.Value = '';
            app.FigureDropDownLabel = uilabel(app.PlotSetTab);
            app.FigureDropDownLabel.Text = 'Figure';
            app.FigureDropDown = uidropdown(app.PlotSetTab);
            app.FigureDropDown.Items = {'Plot 1', 'Plot 2', 'Plot 3'};
            app.FigureDropDown.ValueChangedFcn = createCallbackFcn(app, @FigureDropDownValueChanged, true);
            app.FigureDropDown.Value = 'Plot 1';
            app.PLOTButton = uibutton(app.PlotSetTab, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.VariableDropDownLabel = uilabel(app.PlotSetTab);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.PlotSetTab);
            app.VariableDropDown.Items = {};
            app.VariableDropDown.Value = {};
            app.TimesSpinnerLabel = uilabel(app.PlotSetTab);
            app.TimesSpinnerLabel.Text = 'Time [s]';
            app.TimesSpinner = uispinner(app.PlotSetTab);
            app.AzimutalLabel = uilabel(app.PlotSetTab);
            app.AzimutalLabel.Text = 'Azimutal [º]';
            app.AzimutalSpinner = uispinner(app.PlotSetTab);
            app.ElementmSpinnerLabel = uilabel(app.PlotSetTab);
            app.ElementmSpinnerLabel.Text = 'Element [m]';
            app.ElementmSpinner = uispinner(app.PlotSetTab);
            app.AzimutalEDistButton = uibutton(app.PlotSetTab, 'push');
            app.AzimutalEDistButton.ButtonPushedFcn = createCallbackFcn(app, @AzimutalEDistButtonPushed, true);
            app.AzimutalEDistButton.Text = 'Azimutal E. Dist';
            app.BladeDistButton = uibutton(app.PlotSetTab, 'push');
            app.BladeDistButton.ButtonPushedFcn = createCallbackFcn(app, @BladeDistButtonPushed, true);
            app.BladeDistButton.Text = 'Blade Dist.';
            app.FigureDropDown_2Label = uilabel(app.PlotSetTab);
            app.FigureDropDown_2Label.Text = 'Figure';
            app.FigureDropDown_2 = uidropdown(app.PlotSetTab);
            app.FigureDropDown_2.Items = {'Plot 1', 'Plot 2', 'Plot 3'};
            app.FigureDropDown_2.Value = 'Plot 1';
            app.RotorDistButton = uibutton(app.PlotSetTab, 'push');
            app.RotorDistButton.ButtonPushedFcn = createCallbackFcn(app, @RotorDistButtonPushed, true);
            app.RotorDistButton.Text = 'Rotor Dist.';
            app.BLADEPLOTButton = uibutton(app.UIFigure, 'push');
            app.BLADEPLOTButton.ButtonPushedFcn = createCallbackFcn(app, @BLADEPLOTButtonPushed, true);
            app.BLADEPLOTButton.Text = 'BLADE PLOT';
            app.DAEROButton = uibutton(app.UIFigure, 'push');
            app.DAEROButton.ButtonPushedFcn = createCallbackFcn(app, @DAEROButtonPushed, true);
            app.DAEROButton.Text = '2D AERO ';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ASTRAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end