classdef PARATOR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TimecontrollerLabel             matlab.ui.control.Label
        LOGEditField                    matlab.ui.control.EditField
        LOGEditFieldLabel               matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        RotorTab                        matlab.ui.container.Tab
        ShowButton                      matlab.ui.control.Button
        BladeLabel                      matlab.ui.control.Label
        RotorLabel                      matlab.ui.control.Label
        AirfoilLabel                    matlab.ui.control.Label
        RootBladeDistancemEditField     matlab.ui.control.NumericEditField
        RootBladeDistancemEditFieldLabel  matlab.ui.control.Label
        TipPitchAngleEditField          matlab.ui.control.NumericEditField
        TipPitchAngleEditFieldLabel     matlab.ui.control.Label
        RootPitchAngleEditField         matlab.ui.control.NumericEditField
        RootPitchAngleEditFieldLabel    matlab.ui.control.Label
        NoofBladesEditField             matlab.ui.control.NumericEditField
        NoofBladesEditFieldLabel        matlab.ui.control.Label
        Airfoilthickness01EditField     matlab.ui.control.NumericEditField
        Airfoilthickness01EditFieldLabel  matlab.ui.control.Label
        AirfoilChordmEditField          matlab.ui.control.NumericEditField
        AirfoilChordmEditFieldLabel     matlab.ui.control.Label
        BladeSpanmEditField             matlab.ui.control.NumericEditField
        BladeSpanmEditFieldLabel        matlab.ui.control.Label
        AerodynamicModelButtonGroup     matlab.ui.container.ButtonGroup
        NeuralFoilButton                matlab.ui.control.RadioButton
        AerodasButton                   matlab.ui.control.RadioButton
        PrandtlTipLossesCheckBox        matlab.ui.control.CheckBox
        TwistDistributionButtonGroup    matlab.ui.container.ButtonGroup
        LinearButton                    matlab.ui.control.RadioButton
        NoTwistButton                   matlab.ui.control.RadioButton
        OpenRotorCheckBox               matlab.ui.control.CheckBox
        InputsTab                       matlab.ui.container.Tab
        PitchLabel                      matlab.ui.control.Label
        RollLabel_2                     matlab.ui.control.Label
        phiEditField                    matlab.ui.control.NumericEditField
        phiEditFieldLabel               matlab.ui.control.Label
        thetaEditField                  matlab.ui.control.NumericEditField
        thetaEditFieldLabel             matlab.ui.control.Label
        AttitudeLabel                   matlab.ui.control.Label
        VzEditField                     matlab.ui.control.NumericEditField
        VzEditFieldLabel                matlab.ui.control.Label
        VyEditField                     matlab.ui.control.NumericEditField
        VyEditFieldLabel                matlab.ui.control.Label
        VxEditField                     matlab.ui.control.NumericEditField
        VxEditFieldLabel                matlab.ui.control.Label
        VelocitymsLabel                 matlab.ui.control.Label
        VehicleDragCheckBox             matlab.ui.control.CheckBox
        EarthLabel                      matlab.ui.control.Label
        GravitationalConstantm3kg1s2EditField  matlab.ui.control.NumericEditField
        GravitationalConstantm3kg1s2EditFieldLabel  matlab.ui.control.Label
        EarthMasskgEditField            matlab.ui.control.NumericEditField
        EarthMasskgEditFieldLabel       matlab.ui.control.Label
        EarthRadiusmEditField           matlab.ui.control.NumericEditField
        EarthRadiusmEditFieldLabel      matlab.ui.control.Label
        DeploymenttimesEditField        matlab.ui.control.NumericEditField
        DeploymenttimesEditFieldLabel   matlab.ui.control.Label
        RotorDeploymentLabel            matlab.ui.control.Label
        PayloadMasskgEditField          matlab.ui.control.NumericEditField
        PayloadMasskgEditFieldLabel     matlab.ui.control.Label
        VEICHLELabel                    matlab.ui.control.Label
        INITIALCONDITIONSLabel          matlab.ui.control.Label
        HeightmEditField                matlab.ui.control.NumericEditField
        HeightmEditFieldLabel           matlab.ui.control.Label
        SimulatorTab                    matlab.ui.container.Tab
        InitValuesforsimulationEditField  matlab.ui.control.NumericEditField
        InitValuesforsimulationEditFieldLabel  matlab.ui.control.Label
        INITIALIZATIONLabel             matlab.ui.control.Label
        ATMOSPHEREMODELLabel            matlab.ui.control.Label
        ChooseAMButtonGroup             matlab.ui.container.ButtonGroup
        Hyperlink3                      matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        StandardAtmosphereButton        matlab.ui.control.RadioButton
        EAMButton                       matlab.ui.control.RadioButton
        TimeLimitsEditField             matlab.ui.control.NumericEditField
        TimeLimitsEditFieldLabel        matlab.ui.control.Label
        SOLVERLabel                     matlab.ui.control.Label
        TIMEDISCRETIZATIONLabel         matlab.ui.control.Label
        ChooseSolverButtonGroup         matlab.ui.container.ButtonGroup
        RK4thButton                     matlab.ui.control.RadioButton
        AnalyticButton                  matlab.ui.control.RadioButton
        ode23tbButton                   matlab.ui.control.RadioButton
        ode23tButton                    matlab.ui.control.RadioButton
        ode23sButton                    matlab.ui.control.RadioButton
        ode15sButton                    matlab.ui.control.RadioButton
        Hyperlink                       matlab.ui.control.Hyperlink
        od113Button                     matlab.ui.control.RadioButton
        ode89Button                     matlab.ui.control.RadioButton
        ode78Button                     matlab.ui.control.RadioButton
        ode45Button                     matlab.ui.control.RadioButton
        ode23Button                     matlab.ui.control.RadioButton
        NoElementsEditField             matlab.ui.control.NumericEditField
        NoElementsEditFieldLabel        matlab.ui.control.Label
        BLADEDISCRETIZATIONLabel        matlab.ui.control.Label
        TimestepsEditField              matlab.ui.control.NumericEditField
        TimestepsEditFieldLabel         matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        stall_region_button             matlab.ui.control.Button
        STALLLabel                      matlab.ui.control.Label
        VelocityButton_rotor_2          matlab.ui.control.Button
        rotor_torque_button_2           matlab.ui.control.Button
        SHAFTLabel                      matlab.ui.control.Label
        TrajectoryButton                matlab.ui.control.Button
        soundspeed                      matlab.ui.control.Button
        kinematicViscosity              matlab.ui.control.Button
        dynamicViscosity                matlab.ui.control.Button
        pressure                        matlab.ui.control.Button
        density                         matlab.ui.control.Button
        temperature                     matlab.ui.control.Button
        ATMOSPHERELabel                 matlab.ui.control.Label
        AngularAccelerationButton       matlab.ui.control.Button
        AngularvelociyButton            matlab.ui.control.Button
        EulerAnglesButton               matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        rotor_torque_button             matlab.ui.control.Button
        total_forceButton               matlab.ui.control.Button
        FORCESLabel                     matlab.ui.control.Label
        drag_forceButton                matlab.ui.control.Button
        rotor_forceButton               matlab.ui.control.Button
        gravity_forceButton             matlab.ui.control.Button
        BLADELabel                      matlab.ui.control.Label
        ROTORLabel                      matlab.ui.control.Label
        AccelerationButton_rotor        matlab.ui.control.Button
        PositionButton_rotor            matlab.ui.control.Button
        KINEMATICSLabel                 matlab.ui.control.Label
        AccelerationButton              matlab.ui.control.Button
        VelocityButton                  matlab.ui.control.Button
        PositionButton                  matlab.ui.control.Button
        ChooseDataButtonGroup           matlab.ui.container.ButtonGroup
        StallModeButton                 matlab.ui.control.RadioButton
        StallAngleButton                matlab.ui.control.RadioButton
        dF_z_prandtlButton              matlab.ui.control.RadioButton
        PrandtlDistButton               matlab.ui.control.RadioButton
        v_zButton                       matlab.ui.control.RadioButton
        v_yButton                       matlab.ui.control.RadioButton
        element_stateButton             matlab.ui.control.RadioButton
        MaButton                        matlab.ui.control.RadioButton
        ReButton                        matlab.ui.control.RadioButton
        U_RButton                       matlab.ui.control.RadioButton
        U_PButton                       matlab.ui.control.RadioButton
        U_TButton                       matlab.ui.control.RadioButton
        CdButton                        matlab.ui.control.RadioButton
        ClButton                        matlab.ui.control.RadioButton
        total_vButton                   matlab.ui.control.RadioButton
        phiButton                       matlab.ui.control.RadioButton
        thetaButton                     matlab.ui.control.RadioButton
        alphaButton                     matlab.ui.control.RadioButton
        ang_vButton                     matlab.ui.control.RadioButton
        v_xButton                       matlab.ui.control.RadioButton
        dDButton                        matlab.ui.control.RadioButton
        dLButton                        matlab.ui.control.RadioButton
        dtau_zButton                    matlab.ui.control.RadioButton
        dtau_yButton                    matlab.ui.control.RadioButton
        dtau_xButton                    matlab.ui.control.RadioButton
        dF_zButton                      matlab.ui.control.RadioButton
        dF_yButton                      matlab.ui.control.RadioButton
        dF_xButton                      matlab.ui.control.RadioButton
        OtherTab                        matlab.ui.container.Tab
        SaveallButton                   matlab.ui.control.Button
        SavedataButton                  matlab.ui.control.Button
        FilenametosaveEditField         matlab.ui.control.EditField
        FilenametosaveEditFieldLabel    matlab.ui.control.Label
        SAVEDATALabel                   matlab.ui.control.Label
        CommandlineoutputCheckBox       matlab.ui.control.CheckBox
        OutputfileEditField             matlab.ui.control.EditField
        OutputfileEditFieldLabel        matlab.ui.control.Label
        AERODASCONFIGURATIONLabel       matlab.ui.control.Label
        SAVEPLOTLabel                   matlab.ui.control.Label
        ChoosethefiletypetosavepictureButtonGroup  matlab.ui.container.ButtonGroup
        pdfButton                       matlab.ui.control.RadioButton
        epsButton                       matlab.ui.control.RadioButton
        jpgButton                       matlab.ui.control.RadioButton
        pngButton                       matlab.ui.control.RadioButton
        NametosavefigureEditField       matlab.ui.control.EditField
        NametosavefigureEditFieldLabel  matlab.ui.control.Label
        PathtosaveEditField             matlab.ui.control.EditField
        PathtosaveEditFieldLabel        matlab.ui.control.Label
        SaveFigureButton                matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        NextButton                      matlab.ui.control.Button
        PrevButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        runState = false;

        plotKinematicsData = false;
        plotElementsData = false;

        simulationInputs = [];
        simulationResults = [];

        plotBladeElemtensVariables = false;   

        timeStep_forPlot = 1;
        elementsData_length;
        bladeNo_forPlot = 1;

        non_stall_time = -1;

        version = 1.0

        FolderPaths % Cell array to store folder paths

    end
    
    methods (Access = private)
               

        function runValidation = loadInputs(app)

            app.simulationInputs.No_elements = app.NoElementsEditField.Value;

            app.simulationInputs.BladeLength = app.BladeSpanmEditField.Value;

            app.simulationInputs.RootBladeDistance = app.RootBladeDistancemEditField.Value;            

            app.simulationInputs.init_height = app.HeightmEditField.Value;

            app.simulationInputs.M_payload = app.PayloadMasskgEditField.Value;

            app.simulationInputs.t_deploy_rotor = app.DeploymenttimesEditField.Value;

            app.simulationInputs.dt = app.TimestepsEditField.Value;

            selectedSolver = app.ChooseSolverButtonGroup.SelectedObject.Text;

            if strcmp(selectedSolver, 'ode23')
                app.simulationInputs.solver = 1;
            elseif strcmp(selectedSolver, 'ode45')
                app.simulationInputs.solver = 2;
            elseif strcmp(selectedSolver, 'ode78')
                app.simulationInputs.solver = 3;
            elseif strcmp(selectedSolver, 'ode89')
                app.simulationInputs.solver = 4;
            elseif strcmp(selectedSolver, 'ode113')
                app.simulationInputs.solver = 5;
            elseif strcmp(selectedSolver, 'ode15s')
                app.simulationInputs.solver = 6;
            elseif strcmp(selectedSolver, 'ode23s')
                app.simulationInputs.solver = 7;
            elseif strcmp(selectedSolver, 'ode23t')
                app.simulationInputs.solver = 7;
            elseif strcmp(selectedSolver, 'ode23tb')
                app.simulationInputs.solver = 9;
            elseif strcmp(selectedSolver, 'Analytic')
                app.simulationInputs.solver = 10;
            elseif strcmp(selectedSolver, 'RK-4th')
                app.simulationInputs.solver = 11;                
            end

            app.simulationInputs.M_terra = app.EarthMasskgEditField.Value;
            app.simulationInputs.R_terra = app.EarthRadiusmEditField.Value;
            app.simulationInputs.G = app.GravitationalConstantm3kg1s2EditField.Value;

            app.simulationInputs.c_rotor = app.AirfoilChordmEditField.Value;
            app.simulationInputs.tc = app.Airfoilthickness01EditField.Value;

            app.simulationInputs.Nb = app.NoofBladesEditField.Value;

            app.simulationInputs.root_theta = app.RootPitchAngleEditField.Value;
            app.simulationInputs.tip_theta = app.TipPitchAngleEditField.Value;
 
            app.simulationInputs.time_limit_sim = app.TimeLimitsEditField.Value;

            app.simulationInputs.openRotor = app.OpenRotorCheckBox.Value;

            app.simulationInputs.vehicleDrag = app.VehicleDragCheckBox.Value;

            app.simulationInputs.outputFile_AERODAS = app.OutputfileEditField.Value;
            app.simulationInputs.cmd_debug_AERODAS = app.CommandlineoutputCheckBox.Value;

            selectedTwist = app.TwistDistributionButtonGroup.SelectedObject.Text;

            if strcmp(selectedTwist, 'No Twist')
                app.simulationInputs.twistDistribution = 1;
            else
                app.simulationInputs.twistDistribution = 2;
            end

            selectedAeroModel = app.AerodynamicModelButtonGroup.SelectedObject.Text;
            
            if strcmp(selectedAeroModel, 'Aerodas')
                app.simulationInputs.AerodynamicModel = 1;
            else
                app.simulationInputs.AerodynamicModel = 2;
            end

            selectedButton = app.ChooseAMButtonGroup.SelectedObject.Text;

            if strcmp(selectedButton, 'EAM')
                app.simulationInputs.atmosphereModelSelector = 1;
            else
                app.simulationInputs.atmosphereModelSelector = 2;
            end

            app.simulationInputs.prandtlTipLosses = app.PrandtlTipLossesCheckBox.Value;

            app.simulationInputs.init_values = app.InitValuesforsimulationEditField.Value;

            app.simulationInputs.cdm_general_debbug = 1;

            % INITIAL VELOCITIES
            app.simulationInputs.Vx = app.VxEditField.Value;
            app.simulationInputs.Vy = app.VyEditField.Value;
            app.simulationInputs.Vz = app.VzEditField.Value;

             % INITIAL ORIENTATION
             app.simulationInputs.phi = app.phiEditField.Value;
             app.simulationInputs.theta = app.thetaEditField.Value;

            runValidation = true;
        end     
                       
        function loadDataToPlot(app, dataToPlot)

            if app.runState == false
                app.plotKinematicsData = false;
                app.plotElementsData = false;
                app.Switch.Value = 'Off';
                app.LOGEditField.Value = "Error - Impossible to plot data - Run simulation first";
                return;
            end

            % Add elements here for the buttons options 
            if app.plotKinematicsData == true && app.plotElementsData == false
                
                app.Switch.Value = 'Off';

                s_x_label = 't [s]';
                plotMode = 'Kinematics';

                switch dataToPlot
                    case 'Vehicle Position'
                        app.LOGEditField.Value = "Plotting Vehicle Position...";
                        y_data_plot = app.simulationResults.position;
                        x_data_plot = app.simulationResults.time_main;
                        s_title = "Vehicle Position";
                        s_y_label = "P [m]";
                        
                    case 'Vehicle Velocity'
                        app.LOGEditField.Value = "Plotting Vehicle Velocity...";
                        y_data_plot = app.simulationResults.velocity;
                        x_data_plot = app.simulationResults.time_main;
                        s_title = "Vehicle Velocity";
                        s_y_label = "V [m/s]";
                        
                    case 'Vehicle Acceleration'
                        app.LOGEditField.Value = "Plotting Vehicle Acceleration...";
                        y_data_plot = app.simulationResults.acceleration;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Vehicle Acceleration";
                        s_y_label = "A [m/s^2]";
                    
                    case 'Euler Angles'
                        app.LOGEditField.Value = "Plotting Vehicle Euler Angles...";
                        y_data_plot = app.simulationResults.eulerAngles;
                        x_data_plot = app.simulationResults.time_main;
                        s_title = "Vehicle Euler Angles";
                        s_y_label = "\Omega [rad]";

                    case 'Angular Velocity'
                        app.LOGEditField.Value = "Plotting Vehicle Angular Velocity...";
                        y_data_plot = app.simulationResults.angularVelocity;
                        x_data_plot = app.simulationResults.time_main;
                        s_title = "Vehicle Angular Velocity";
                        s_y_label = "\omega [rad/s]";

                    case 'Angular Acceleration'
                        app.LOGEditField.Value = "Plotting Vehicle Angular Velocity...";
                        y_data_plot = app.simulationResults.angularAcceleration;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Vehicle Angular Acceleration";
                        s_y_label = "d\omega [rad/s^2]";
                        
                    case 'Rotor Position'
                        app.LOGEditField.Value = "Plotting Rotor Position...";
                        y_data_plot = app.simulationResults. rotor_position;
                        x_data_plot = app.simulationResults.time_main;
                        s_title = "Rotor Position";
                        s_y_label = "Rotor Position [rad]";

                    case 'Rotor Velocity'
                        app.LOGEditField.Value = "Plotting Rotor Velocity in rad/s...";
                        y_data_plot = app.simulationResults. rotor_velocity;
                        x_data_plot = app.simulationResults.time_main;
                        s_title = "Rotor Velocity";
                        s_y_label = "Rotor Velocity [rad/s]";

                    case 'Angular Velocity RPM'
                        app.LOGEditField.Value = "Plotting Rotor Velocity in RPM...";
                        y_data_plot = app.simulationResults. rotor_velocity * 180/pi;
                        x_data_plot = app.simulationResults.time_main;
                        s_title = "Rotor Velocity";
                        s_y_label = "Rotor Velocity [RPM]";

                    case 'Rotor Acceleration'
                        app.LOGEditField.Value = "Plotting Rotor Acceleration...";
                        y_data_plot = app.simulationResults. rotor_acceleration;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Rotor Acceleration";
                        s_y_label = "Rotor Acceleration [rad/s^2]";
                        
                    case 'Gravity Force'
                        app.LOGEditField.Value = "Plotting Gravity Force...";
                        y_data_plot = app.simulationResults.gravityForce;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Gravity Force";
                        s_y_label = "Force [N]";
                        
                    case 'Rotor Force'
                        app.LOGEditField.Value = "Plotting Rotor Force...";
                        y_data_plot = app.simulationResults.rotorForce;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Rotor Force";
                        s_y_label = "Force [N]";
                        
                    case 'Cilinder Drag Force'
                        app.LOGEditField.Value = "Plotting Cilinder Drag Force...";
                        y_data_plot = app.simulationResults.cilinderDragForce;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Cilinder Drag Force";
                        s_y_label = "Force [N]";
                        
                    case 'Total Force'
                        app.LOGEditField.Value = "Plotting Total Force...";
                        y_data_plot = app.simulationResults.totalForce;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Total Force";
                        s_y_label = "Force [N]";
                        
                    case 'Rotor Torque'
                        app.LOGEditField.Value = "Plotting Rotor Torque...";
                        y_data_plot = app.simulationResults.rotor_torque;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Rotor Torque";
                        s_y_label = "Torque [Nm]";

                    case 'Shaft Torque'
                        app.LOGEditField.Value = "Plotting Shaft Torque...";
                        y_data_plot = app.simulationResults.shaft_torque;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Shaft Torque";
                        s_y_label = "Torque [Nm]";

                    case 'Atmospheric Temperature'

                        y_data_plot = app.simulationResults.temperature;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Temperature";
                        s_y_label = "T [K]";
                    
                    case 'Atmospheric Density'

                        y_data_plot = app.simulationResults.density;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Density";
                        s_y_label = "\rho [kg/m^3]";

                    case 'Atmospheric Pressure' 

                        y_data_plot = app.simulationResults.pressure;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Pressure";
                        s_y_label = "p [Pa]";
                   
                    case 'Dynamic Viscosity' 

                        y_data_plot = app.simulationResults.dynamicViscosity;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Dynamic Viscosity";
                        s_y_label = "\nu [m^2/s^{-1}]";
                    
                    case 'Kinematic Viscosity' 

                        y_data_plot = app.simulationResults.kinematicViscosity;
                            x_data_plot = app.simulationResults.time_second;
                            s_title = "Kinematic Viscosity";
                            s_y_label = "\mu [m^2/s^{-1}]";
                    
                    case 'Sound Speed' 

                        y_data_plot = app.simulationResults.soundSpeed;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Sound Speed";
                        s_y_label = "a [m/s]";

                    case 'Stall Region' 

                        y_data_plot = app.simulationResults.stall_percentage;
                        x_data_plot = app.simulationResults.time_second;
                        s_title = "Blade Region in Stall";
                        s_y_label = "%";
                        

                    otherwise
                        app.plotKinematicsData = true;
                        app.plotElementsData = false;
                        app.LOGEditField.Value = "Error - Impossible to plot data - Run simulation first";
                end

                updatePlot(app, x_data_plot, y_data_plot', s_title, s_x_label, s_y_label, plotMode)

                app.plotKinematicsData = false;
                app.plotElementsData = false;

            % Add elemetns here for the radio type menu
            elseif app.plotKinematicsData == false && app.plotElementsData == true

                if app.simulationInputs.openRotor == false
                    app.plotKinematicsData = false;
                    app.plotElementsData = false;
                    app.LOGEditField.Value = "Error - Impossible to plot rotor data - Run with rotor option enabled";
                    app.Switch.Value = 'Off';
                    return;
                end 
                
                selectedButton = app.ChooseDataButtonGroup.SelectedObject.Text;
                
                % Obtenha o instante de tempo a partir da estrutura
                instantTime = app.simulationResults.b_time(app.timeStep_forPlot);
                messageTemplate = 'Plotting Blade %s at instant t = %.4f ...';
                
                % Use um switch para definir a variável específica
                switch selectedButton
                    case 'dF_x'
                        message = sprintf(messageTemplate, 'dF_x', instantTime);
                        y_data_plot = app.simulationResults.b_df_x(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Force on X-axis";
                        s_y_label = "Force [N]";
                
                    case 'dF_y'
                        message = sprintf(messageTemplate, 'dF_y', instantTime);
                        y_data_plot = app.simulationResults.b_df_y(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Force on Y-axis";
                        s_y_label = "Force [N]";
                
                    case 'dF_z'
                        message = sprintf(messageTemplate, 'dF_z', instantTime);
                        y_data_plot = app.simulationResults.b_df_z(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Force on Z-axis";
                        s_y_label = "Force [N]";
                
                    case 'dtau_x'
                        message = sprintf(messageTemplate, 'dtau_x', instantTime);
                        y_data_plot = app.simulationResults.b_dtau_x(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Torque on X-axis";
                        s_y_label = "Torque [Nm]";
                
                    case 'dtau_y'
                        message = sprintf(messageTemplate, 'dtau_y', instantTime);
                        y_data_plot = app.simulationResults.b_dtau_y(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Torque on Y-axis";
                        s_y_label = "Torque [Nm]";
                
                    case 'dtau_z'
                        message = sprintf(messageTemplate, 'dtau_z', instantTime);
                        y_data_plot = app.simulationResults.b_dtau_z(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Torque on Z-axis";
                        s_y_label = "Torque [Nm]";
                
                    case 'v_x'
                        message = sprintf(messageTemplate, 'v_x', instantTime);
                        y_data_plot = app.simulationResults.b_v_x(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Velocity on X-axis";
                        s_y_label = "Velocity [m/s]";
                
                    case 'v_y'
                        message = sprintf(messageTemplate, 'v_y', instantTime);
                        y_data_plot = app.simulationResults.b_v_y(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Velocity on Y-axis";
                        s_y_label = "Velocity [m/s]";
                
                    case 'v_z'
                        message = sprintf(messageTemplate, 'v_z', instantTime);
                        y_data_plot = app.simulationResults.b_v_z(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Velocity on Z-axis";
                        s_y_label = "Velocity [m/s]";
                
                    case 'ang_v'
                        message = sprintf(messageTemplate, 'ang_v', instantTime);
                        y_data_plot = app.simulationResults.b_ang_v(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Angular Velocity";
                        s_y_label = "Angular Velocity [rad/s]";
                
                    case 'total_v'
                        message = sprintf(messageTemplate, 'total_v', instantTime);
                        y_data_plot = app.simulationResults.b_total_v(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Total Velocity";
                        s_y_label = "Velocity [m/s]";
                
                    case 'dL'
                        message = sprintf(messageTemplate, 'dL', instantTime);
                        y_data_plot = app.simulationResults.b_dL(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Lift Force";
                        s_y_label = "Lift [N]";
                
                    case 'dD'
                        message = sprintf(messageTemplate, 'dD', instantTime);
                        y_data_plot = app.simulationResults.b_dD(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Drag Force";
                        s_y_label = "Drag [N]";
                
                    case 'alpha'
                        message = sprintf(messageTemplate, 'alpha', instantTime);
                        y_data_plot = app.simulationResults.b_alpha(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Angle of Attack";
                        s_y_label = "Angle [degrees]";
                
                    case 'theta'
                        message = sprintf(messageTemplate, 'theta', instantTime);
                        y_data_plot = app.simulationResults.b_theta(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Pitch Angle";
                        s_y_label = "Angle [degrees]";
                
                    case 'phi'
                        message = sprintf(messageTemplate, 'phi', instantTime);
                        y_data_plot = app.simulationResults.b_phi(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Azimuth Angle";
                        s_y_label = "Angle [degrees]";
                
                    case 'element_state'
                        message = sprintf(messageTemplate, 'element_state', instantTime);
                        y_data_plot = app.simulationResults.b_element_state(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Element State";
                        s_y_label = "State";
                
                    case 'Ma'
                        message = sprintf(messageTemplate, 'Ma', instantTime);
                        y_data_plot = app.simulationResults.b_Ma(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Mach Number";
                        s_y_label = "Mach";
                
                    case 'Re'
                        message = sprintf(messageTemplate, 'Re', instantTime);
                        y_data_plot = app.simulationResults.b_Re(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Reynolds Number";
                        s_y_label = "Re";
                
                    case 'Cl'
                        message = sprintf(messageTemplate, 'Cl', instantTime);
                        y_data_plot = app.simulationResults.b_Cl(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Lift Coefficient";
                        s_y_label = "Cl";
                
                    case 'Cd'
                        message = sprintf(messageTemplate, 'Cd', instantTime);
                        y_data_plot = app.simulationResults.b_Cd(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Drag Coefficient";
                        s_y_label = "Cd";
                
                    case 'U_T'
                        message = sprintf(messageTemplate, 'U_T', instantTime);
                        y_data_plot = app.simulationResults.b_U_T(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Tangential Velocity";
                        s_y_label = "Velocity [m/s]";
                
                    case 'U_R'
                        message = sprintf(messageTemplate, 'U_R', instantTime);
                        y_data_plot = app.simulationResults.b_U_R(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Radial Velocity";
                        s_y_label = "Velocity [m/s]";
                
                    case 'U_P'
                        message = sprintf(messageTemplate, 'U_P', instantTime);
                        y_data_plot = app.simulationResults.b_U_P(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Perpendicular Velocity";
                        s_y_label = "Velocity [m/s]";
                    
                    case 'Prandtl Dist.'
                        message = sprintf(messageTemplate, 'Prandtl Distribution', instantTime);
                        y_data_plot = app.simulationResults.b_f_prandtl(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Prandtl Distribution";
                        s_y_label = "F";

                    case 'dF_z_prandtl'
                        message = sprintf(messageTemplate, 'Force on Z-axis with Prandtl Distribution', instantTime);
                        y_data_plot = app.simulationResults.b_df_z_prandtl(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Force on Z-axis";
                        s_y_label = "d_F_z Prandtl";

                    case 'Stall Mode'
                        message = sprintf(messageTemplate, 'Stall Mode', instantTime);
                        y_data_plot = app.simulationResults.b_stallMode(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Stall Mode";
                        s_y_label = "Mode - 1 if stalled - 0 if not stalled";


                    case 'Stall Angle'
                        message = sprintf(messageTemplate, 'Stall Angle', instantTime);
                        y_data_plot = app.simulationResults.b_stallAngle(app.bladeNo_forPlot,:,app.timeStep_forPlot);
                        s_title = "Stall Angle";
                        s_y_label = "Angle [º]";                   
                
                    otherwise
                        error('Unknown selection');
                end

                
                % Atribua a mensagem formatada ao campo de texto
                app.LOGEditField.Value = message;

                s_title = sprintf('%s for Blade no. %d at time %.5f', s_title, app.bladeNo_forPlot, instantTime);

                x_data_plot = app.simulationResults.pos_sec;
                s_x_label = 'x [m]';
                plotMode = 'BladeElements';

                updatePlot(app, x_data_plot, y_data_plot, s_title, s_x_label, s_y_label, plotMode)


            else
                app.LOGEditField.Value = 'Error - Not a valid option - Check simulation inputs';
            end

        end

        function updatePlot(app, x, y, s_title, s_x_label, s_y_label, plotMode)

            if ~isvalid(app.UIAxes) || isempty(x) || isempty(y)
                return;  % Exit function if UIAxes is not valid or data is empty
            end
            
            % Define colors for each row
            colors = {'k', 'r', 'b'};
            
            % Define legend labels (assuming up to 3 plots)
            legendLabels_axis = {'X-axis', 'Y-axis', 'Z-axis'};

            % Clear previous plots and reset hold to start fresh
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');        
            
            if strcmp(plotMode, 'Kinematics')
                for i = 1:size(y, 1)
                        plot(app.UIAxes, x, y(i,:), colors{i}, 'LineWidth', 1.5);                
                end
            elseif strcmp(plotMode, 'BladeElements')
                plot(app.UIAxes,x,y, 'k-o', 'LineWidth', 1.5)
            end

            if app.simulationInputs.openRotor == true
                % Add a vertical dashed purple line at app.t_deploy_rotor
                if strcmp(plotMode, 'Kinematics')
                    xline(app.UIAxes, app.simulationInputs.t_deploy_rotor, 'Color', 'm', 'LineStyle', '--', 'LineWidth', 1.5);
                end
            end

            if app.non_stall_time > 0
                % Add a vertical dashed purple line at app.t_deploy_rotor
                if strcmp(plotMode, 'Kinematics')
                    xline(app.UIAxes, app.non_stall_time, 'Color', 'g', 'LineStyle', '--', 'LineWidth', 1.5);
                end
            end

            title(app.UIAxes, s_title);
            xlabel(app.UIAxes, s_x_label);
            ylabel(app.UIAxes, s_y_label);

            if strcmp(plotMode, 'Kinematics')
                legend(app.UIAxes, legendLabels_axis(1:size(y, 1)));
            elseif strcmp(plotMode, 'BladeElements')
                % Remove any existing legend
                legend(app.UIAxes, 'off');
            end

            hold(app.UIAxes, 'off');
        end
                   
        function savePlotToImage(app, fileName, userPath, fileType, modeSingle)
            % Define the base path to prepend
            basePath = './sim/saves/';
            
            % Construct the full path by combining basePath and userPath
            fullPath = fullfile(basePath, userPath);
            
            % Verifica se a pasta já existe, caso contrário, cria-a
            if ~exist(fullPath, 'dir')
                mkdir(fullPath);  % Cria o diretório
            end
            
            % Verifique se o fileName e o fileType não estão vazios
            if isempty(fileName)
                uialert(app.UIFigure, 'File name is empty.', 'Error');
                return;
            elseif isempty(fileType)
                uialert(app.UIFigure, 'File type is empty.', 'Error');
                return;
            end
            
            if modeSingle == true
                n_name = [fileName, '.', fileType];
            else
                n_name = fileName + '.' + fileType;
            end
            % Construa o nome completo do arquivo incluindo a extensão
            fullFileName = fullfile(fullPath, n_name);
            
            % Capture the image from the UIAxes object
            % Get the UIAxes object
                uiAxes = app.UIAxes; 
                
            % Ensure the axes object is valid before proceeding
            if isvalid(uiAxes)
                switch fileType
                    case {'eps', 'pdf'}
                        exportgraphics(uiAxes, fullFileName, 'ContentType', 'vector');
                    case {'png', 'jpg'}
                        exportgraphics(uiAxes, fullFileName, 'ContentType', 'image');
                    otherwise
                        uialert(app.UIFigure, 'Unsupported file type for export.', 'Error');
                        return;
                end
            else
                uialert(app.UIFigure, 'UIAxes object is not valid.', 'Error');
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            clear
            close all
           
            app.runState = false;

            app.LOGEditField.Value = "Ready to run";
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            app.Switch.Value = 'Off';
            app.non_stall_time = -1;

            tic
            
            runValidated = loadInputs(app);

            if ~runValidated 
                app.LOGEditField.Value = "Error - Check inputs";

                app.runState = false;
                return;
            else
                app.LOGEditField.Value = "Running...";
                
                clc
                
                data = run_simulation(app.simulationInputs); 
                
                app.simulationResults = data;

                app.runState = true;

                elapsed_time = toc;
        
                % Convert elapsed time to hours, minutes, and seconds
                hours = floor(elapsed_time / 3600);
                minutes = floor(mod(elapsed_time, 3600) / 60);
                seconds = round(mod(elapsed_time, 60));
            
                % Update the LOGEditField with the formatted elapsed time as integers
                if app.simulationResults.runSuccessSim
                    app.LOGEditField.Value = sprintf("The simulation ran successfully! Elapsed time: %02dh:%02dm:%02ds", hours, minutes, seconds);
                else
                    app.LOGEditField.Value = sprintf("The simulation ran unsuccessfully! Elapsed time: %02dh:%02dm:%02ds", hours, minutes, seconds);
                end

                if app.simulationInputs.openRotor == true
                    app.elementsData_length = length(app.simulationResults.b_time);
                    
                    % Definindo os limites do slider
                    app.Slider.Limits = [app.simulationResults.b_time(1), app.simulationResults.b_time(end)];
                    
                    % Definindo as marcas principais (MajorTicks) de 10% em 10% do intervalo
                    num_major_ticks = 11;  % 0%, 10%, 20%, ..., 100%
                    app.Slider.MajorTicks = linspace(app.Slider.Limits(1), app.Slider.Limits(2), num_major_ticks);
                    
                    % Definindo as labels para as marcas principais (opcional)
                    app.Slider.MajorTickLabels = arrayfun(@(x) sprintf('%.1f', x), app.Slider.MajorTicks, 'UniformOutput', false);
                    
                    % Definindo as marcas menores (MinorTicks) com 3 divisões entre cada marca principal
                    num_minor_ticks = (num_major_ticks - 1) * 3 + 1;  % 3 divisões entre cada par de marcas principais
                    app.Slider.MinorTicks = linspace(app.Slider.Limits(1), app.Slider.Limits(2), num_minor_ticks);

                    
                    % procurar o instante em que o rotor deixa de estar em
                    % stall

                    [~, i] = min(abs([app.simulationResults.stall_percentage] - 10));
                    
                    app.non_stall_time = app.simulationResults.time_main(i);
                    
                end

            end            
            

        end

        % Selection changed function: ChooseDataButtonGroup
        function ChooseDataButtonGroupSelectionChanged(app, event)
            value = app.Switch.Value;            
                      
            if strcmp(value, 'On')
                loadDataToPlot(app, 'RadioMenuItem')
            else
                app.LOGEditField.Value = 'Error - Enable option to plot blade data';
            end

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            if  app.plotElementsData == true       
                sliderValue = app.Slider.Value;
                [~, app.timeStep_forPlot] = min(abs(app.simulationResults.b_time - sliderValue));
                loadDataToPlot(app, 'RadioMenuItem')
            else
                app.LOGEditField.Value = 'Error - Enable option to plot blade data';
            end

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;            
                      
            if strcmp(value, 'On')
                if app.runState == false
                    app.Switch.Value = 'Off';
                    app.LOGEditField.Value = "Error - Impossible to plot data - Run simulation first";
                    return;
                end

                app.plotKinematicsData = false;
                app.plotElementsData = true;

                sliderValue = app.Slider.Value;
                [~, app.timeStep_forPlot] = min(abs(app.simulationResults.b_time - sliderValue));
                loadDataToPlot(app, 'RadioMenuItem');

            else
                app.plotKinematicsData = false;
                app.plotElementsData = false;
            end
            
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            
            
            if app.plotElementsData == true
                if app.timeStep_forPlot > 1 
                    app.timeStep_forPlot = app.timeStep_forPlot - 1;
                    loadDataToPlot(app, 'RadioMenuItem');
                end
            else
                app.LOGEditField.Value = 'Error - Enable option to plot blade data';
            end

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.plotElementsData == true
                if app.timeStep_forPlot < app.elementsData_length
                    app.timeStep_forPlot = app.timeStep_forPlot + 1;
                    loadDataToPlot(app, 'RadioMenuItem')
                end
            else
                app.LOGEditField.Value = 'Error - Enable option to plot blade data';
            end
    
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
                        
            % Get the file name from the text field
            fileName = app.NametosavefigureEditField.Value;
            
            % Get the path to save the file from the text field
            userPath = app.PathtosaveEditField.Value;

            % Get the file type selected by the user
            fileType = app.ChoosethefiletypetosavepictureButtonGroup.SelectedObject.Text;

            savePlotToImage(app, fileName, userPath, fileType, true)

        end

        % Button pushed function: PositionButton
        function PositionButtonPushed(app, event)
              
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Vehicle Position')

        end

        % Button pushed function: VelocityButton
        function VelocityButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Vehicle Velocity')

        end

        % Button pushed function: AccelerationButton
        function AccelerationButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Vehicle Acceleration')
            
        end

        % Button pushed function: PositionButton_rotor
        function PositionButton_rotorPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Rotor Position')

            
        end

        % Callback function
        function VelocityButton_rotorPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Rotor Velocity')
            
        end

        % Button pushed function: AccelerationButton_rotor
        function AccelerationButton_rotorPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Rotor Acceleration')

        end

        % Button pushed function: gravity_forceButton
        function gravity_forceButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Gravity Force')

        end

        % Button pushed function: rotor_forceButton
        function rotor_forceButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Rotor Force')

        end

        % Button pushed function: drag_forceButton
        function drag_forceButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Cilinder Drag Force')

        end

        % Button pushed function: total_forceButton
        function total_forceButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Total Force')

        end

        % Button pushed function: rotor_torque_button
        function rotor_torque_buttonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Rotor Torque')

        end

        % Button pushed function: EulerAnglesButton
        function EulerAnglesButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Euler Angles')
        end

        % Button pushed function: AngularvelociyButton
        function AngularvelociyButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Angular Velocity')
        end

        % Button pushed function: VelocityButton_rotor_2
        function VelocityButton_rotor_2Pushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Angular Velocity RPM')
        end

        % Button pushed function: AngularAccelerationButton
        function AngularAccelerationButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Angular Acceleration')
        end

        % Button pushed function: temperature
        function temperaturePushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;

            loadDataToPlot(app, 'Atmospheric Temperature')
        end

        % Button pushed function: density
        function densityPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;
            
            loadDataToPlot(app, 'Atmospheric Density')
        end

        % Button pushed function: pressure
        function pressureButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;
            
            loadDataToPlot(app, 'Atmospheric Pressure')
        end

        % Button pushed function: dynamicViscosity
        function dynamicViscosityButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;
            
            loadDataToPlot(app, 'Dynamic Viscosity')
        end

        % Button pushed function: kinematicViscosity
        function kinematicViscosityButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;
            
            loadDataToPlot(app, 'Kinematic Viscosity')
        end

        % Button pushed function: soundspeed
        function soundspeedButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;
            
            loadDataToPlot(app, 'Sound Speed')
        end

        % Button pushed function: TrajectoryButton
        function TrajectoryButtonPushed(app, event)
            if app.runState == true
                plotTrajectory3D(app.simulationResults.position);
            else
                app.LOGEditField.Value = "Error - Run simulations first";
            end
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            value = app.FilenametosaveEditField.Value;

            % Verifica se a simulação foi executada
            if app.runState == true
                % Define o caminho do diretório e o nome do arquivo com extensão .mat
                directory = "sim/saves/data/";
                file = directory + value + ".mat";
        
                % Verifica se o diretório existe, caso contrário, cria-o
                if ~exist(directory, 'dir')
                    mkdir(directory);  % Cria o diretório se ele não existir
                end
        
                % Exibe o nome do arquivo no console (opcional)
                disp(file);
        
                % Supondo que as variáveis simulationInputs e simulationResults estão no workspace do app
                inputs = app.simulationInputs;  % Obtém os dados de entrada da simulação
                outputs = app.simulationResults;  % Obtém os resultados da simulação
        
                % Salva as variáveis no arquivo .mat
                save(file, 'inputs', 'outputs', '-v7.3');
        
                % Opcional: atualizar o campo LOG com uma mensagem de sucesso
                app.LOGEditField.Value = "Data saved";
        
            else
                % Exibe mensagem de erro no campo LOG se a simulação não foi executada
                app.LOGEditField.Value = "Error - simulate first";
            end


        end

        % Button pushed function: SaveallButton
        function SaveallButtonPushed(app, event)
            
            if app.runState == true
                app.LOGEditField.Value = "Saving data plots as figures";

                app.plotKinematicsData = true;

                %Get the path to save the file from the text field
                folder = app.PathtosaveEditField.Value;
    
                % Get the file type selected by the user
                fileType = app.ChoosethefiletypetosavepictureButtonGroup.SelectedObject.Text;
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Vehicle Position');
                savePlotToImage(app, "position", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Vehicle Velocity');
                savePlotToImage(app, "velocity", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Vehicle Acceleration');
                savePlotToImage(app, "acceleration", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Euler Angles');
                savePlotToImage(app, "euler_angles", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Angular Velocity');
                savePlotToImage(app, "angular_velocity", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Angular Acceleration');
                savePlotToImage(app, "angular_acceleration", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Rotor Position');
                savePlotToImage(app, "rotor_position", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Rotor Velocity');
                savePlotToImage(app, "rotor_velocity", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Rotor Acceleration');
                savePlotToImage(app, "rotor_acceleration", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Gravity Force');
                savePlotToImage(app, "gravity_force", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Rotor Force');
                savePlotToImage(app, "rotor_force", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Cilinder Drag Force');
                savePlotToImage(app, "cilinder_drag_force", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Total Force');
                savePlotToImage(app, "total_force", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Rotor Torque');
                savePlotToImage(app, "rotor_torque", folder, fileType, false);

                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Shaft Torque');
                savePlotToImage(app, "shaft_torque", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Atmospheric Temperature');
                savePlotToImage(app, "atmospheric_temperature", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Atmospheric Density');
                savePlotToImage(app, "atmospheric_density", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Atmospheric Pressure');
                savePlotToImage(app, "atmospheric_pressure", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Dynamic Viscosity');
                savePlotToImage(app, "dynamic_viscosity", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Kinematic Viscosity');
                savePlotToImage(app, "kinematic_viscosity", folder, fileType, false);
                
                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Sound Speed');
                savePlotToImage(app, "sound_speed", folder, fileType, false);

                app.plotKinematicsData = true;            
                loadDataToPlot(app, 'Shaft Torque')
                savePlotToImage(app, "shaft_torque", folder, fileType, false);

                app.plotKinematicsData = true;
                loadDataToPlot(app, 'Stall Region')
                savePlotToImage(app, "sta_region", folder, fileType, false);

                set(0,'ShowHiddenHandles','on'); 
                closeMessageBoxes = findall(0,'Type','figure','Name','Success');
                close(closeMessageBoxes);

                app.plotKinematicsData = false;
                app.plotElementsData = false;

                addpath(genpath('sim'));

            else

                % Exibe mensagem de erro no campo LOG se a simulação não foi executada
                app.LOGEditField.Value = "Error - simulate first";

            end
        end

        % Button pushed function: rotor_torque_button_2
        function rotor_torque_button_2ButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;
            
            loadDataToPlot(app, 'Shaft Torque')
        end

        % Button pushed function: stall_region_button
        function stall_region_buttonButtonPushed(app, event)
            app.plotKinematicsData = true;
            app.plotElementsData = false;
            
            loadDataToPlot(app, 'Stall Region')
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            validation_load_inputs = loadInputs(app);

            if ~validation_load_inputs
                app.LOGEditField.Value = "Error - Check inputs";
                return;
            end

            airfoil = "naca0012";

            Ne = app.simulationInputs.No_elements;

            R = app.simulationInputs.BladeLength;

            e = app.simulationInputs.RootBladeDistance;

            c = app.simulationInputs.c_rotor;

            twistMode = app.simulationInputs.twistDistribution;
            root_theta = app.simulationInputs.root_theta;
            tip_theta = app.simulationInputs.tip_theta;


            plot_blade(airfoil, Ne, R, e, c, twistMode, root_theta, tip_theta);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RotorTab = uitab(app.TabGroup);
            app.RotorTab.Title = 'Rotor';
            app.OpenRotorCheckBox = uicheckbox(app.RotorTab);
            app.OpenRotorCheckBox.Text = 'Open Rotor';
            app.OpenRotorCheckBox.Value = true;
            app.TwistDistributionButtonGroup = uibuttongroup(app.RotorTab);
            app.TwistDistributionButtonGroup.Title = 'Twist Distribution';
            app.NoTwistButton = uiradiobutton(app.TwistDistributionButtonGroup);
            app.NoTwistButton.Text = 'No Twist';
            app.LinearButton = uiradiobutton(app.TwistDistributionButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.PrandtlTipLossesCheckBox = uicheckbox(app.RotorTab);
            app.PrandtlTipLossesCheckBox.Text = 'Prandtl Tip Losses';
            app.AerodynamicModelButtonGroup = uibuttongroup(app.RotorTab);
            app.AerodynamicModelButtonGroup.Title = 'Aerodynamic Model';
            app.AerodasButton = uiradiobutton(app.AerodynamicModelButtonGroup);
            app.AerodasButton.Text = 'Aerodas';
            app.AerodasButton.Value = true;
            app.NeuralFoilButton = uiradiobutton(app.AerodynamicModelButtonGroup);
            app.NeuralFoilButton.Text = 'NeuralFoil';
            app.BladeSpanmEditFieldLabel = uilabel(app.RotorTab);
            app.BladeSpanmEditFieldLabel.Text = 'Blade Span [m]';
            app.BladeSpanmEditField = uieditfield(app.RotorTab, 'numeric');
            app.BladeSpanmEditField.Value = 1.2;
            app.AirfoilChordmEditFieldLabel = uilabel(app.RotorTab);
            app.AirfoilChordmEditFieldLabel.Text = 'Airfoil Chord [m]';
            app.AirfoilChordmEditField = uieditfield(app.RotorTab, 'numeric');
            app.AirfoilChordmEditField.Value = 0.18;
            app.Airfoilthickness01EditFieldLabel = uilabel(app.RotorTab);
            app.Airfoilthickness01EditFieldLabel.Text = 'Airfoil thickness [0-1]';
            app.Airfoilthickness01EditField = uieditfield(app.RotorTab, 'numeric');
            app.Airfoilthickness01EditField.Value = 0.12;
            app.NoofBladesEditFieldLabel = uilabel(app.RotorTab);
            app.NoofBladesEditFieldLabel.Text = 'No. of Blades';
            app.NoofBladesEditField = uieditfield(app.RotorTab, 'numeric');
            app.NoofBladesEditField.Value = 2;
            app.RootPitchAngleEditFieldLabel = uilabel(app.RotorTab);
            app.RootPitchAngleEditFieldLabel.Text = 'Root Pitch Angle';
            app.RootPitchAngleEditField = uieditfield(app.RotorTab, 'numeric');
            app.RootPitchAngleEditField.Value = -6;
            app.TipPitchAngleEditFieldLabel = uilabel(app.RotorTab);
            app.TipPitchAngleEditFieldLabel.Text = 'Tip Pitch Angle';
            app.TipPitchAngleEditField = uieditfield(app.RotorTab, 'numeric');
            app.TipPitchAngleEditField.Value = 3;
            app.RootBladeDistancemEditFieldLabel = uilabel(app.RotorTab);
            app.RootBladeDistancemEditFieldLabel.Text = 'Root-Blade Distance [m]';
            app.RootBladeDistancemEditField = uieditfield(app.RotorTab, 'numeric');
            app.AirfoilLabel = uilabel(app.RotorTab);
            app.AirfoilLabel.Text = 'Airfoil';
            app.RotorLabel = uilabel(app.RotorTab);
            app.RotorLabel.Text = 'Rotor';
            app.BladeLabel = uilabel(app.RotorTab);
            app.BladeLabel.Text = 'Blade';
            app.ShowButton = uibutton(app.RotorTab, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.HeightmEditFieldLabel = uilabel(app.InputsTab);
            app.HeightmEditFieldLabel.Text = 'Height [m]';
            app.HeightmEditField = uieditfield(app.InputsTab, 'numeric');
            app.HeightmEditField.Value = 2000;
            app.INITIALCONDITIONSLabel = uilabel(app.InputsTab);
            app.INITIALCONDITIONSLabel.Text = 'INITIAL CONDITIONS';
            app.VEICHLELabel = uilabel(app.InputsTab);
            app.VEICHLELabel.Text = 'VEICHLE';
            app.PayloadMasskgEditFieldLabel = uilabel(app.InputsTab);
            app.PayloadMasskgEditFieldLabel.Text = 'Payload Mass  [kg]';
            app.PayloadMasskgEditField = uieditfield(app.InputsTab, 'numeric');
            app.PayloadMasskgEditField.Value = 20;
            app.RotorDeploymentLabel = uilabel(app.InputsTab);
            app.RotorDeploymentLabel.Text = 'Rotor Deployment';
            app.DeploymenttimesEditFieldLabel = uilabel(app.InputsTab);
            app.DeploymenttimesEditFieldLabel.Text = 'Deployment time [s]';
            app.DeploymenttimesEditField = uieditfield(app.InputsTab, 'numeric');
            app.DeploymenttimesEditField.Value = 3;
            app.EarthRadiusmEditFieldLabel = uilabel(app.InputsTab);
            app.EarthRadiusmEditFieldLabel.Text = 'Earth Radius [m]';
            app.EarthRadiusmEditField = uieditfield(app.InputsTab, 'numeric');
            app.EarthRadiusmEditField.Editable = 'off';
            app.EarthRadiusmEditField.Value = 6371000;
            app.EarthMasskgEditFieldLabel = uilabel(app.InputsTab);
            app.EarthMasskgEditFieldLabel.Text = 'Earth Mass [kg]';
            app.EarthMasskgEditField = uieditfield(app.InputsTab, 'numeric');
            app.EarthMasskgEditField.Editable = 'off';
            app.EarthMasskgEditField.Value = 5.972e+24;
            app.GravitationalConstantm3kg1s2EditFieldLabel = uilabel(app.InputsTab);
            app.GravitationalConstantm3kg1s2EditFieldLabel.Text = {'Gravitational Constant'; '[m^3 kg^(−1) s^(−2)]'};
            app.GravitationalConstantm3kg1s2EditField = uieditfield(app.InputsTab, 'numeric');
            app.GravitationalConstantm3kg1s2EditField.Editable = 'off';
            app.GravitationalConstantm3kg1s2EditField.Value = 6.674184e-11;
            app.EarthLabel = uilabel(app.InputsTab);
            app.EarthLabel.Text = 'Earth';
            app.VehicleDragCheckBox = uicheckbox(app.InputsTab);
            app.VehicleDragCheckBox.Text = 'Vehicle Drag';
            app.VehicleDragCheckBox.Value = true;
            app.VelocitymsLabel = uilabel(app.InputsTab);
            app.VelocitymsLabel.Text = 'Velocity [m/s]';
            app.VxEditFieldLabel = uilabel(app.InputsTab);
            app.VxEditFieldLabel.Text = 'Vx';
            app.VxEditField = uieditfield(app.InputsTab, 'numeric');
            app.VyEditFieldLabel = uilabel(app.InputsTab);
            app.VyEditFieldLabel.Text = 'Vy';
            app.VyEditField = uieditfield(app.InputsTab, 'numeric');
            app.VzEditFieldLabel = uilabel(app.InputsTab);
            app.VzEditFieldLabel.Text = 'Vz';
            app.VzEditField = uieditfield(app.InputsTab, 'numeric');
            app.AttitudeLabel = uilabel(app.InputsTab);
            app.AttitudeLabel.Text = 'Attitude [º]';
            app.thetaEditFieldLabel = uilabel(app.InputsTab);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.InputsTab, 'numeric');
            app.phiEditFieldLabel = uilabel(app.InputsTab);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.InputsTab, 'numeric');
            app.RollLabel_2 = uilabel(app.InputsTab);
            app.RollLabel_2.Text = 'Roll';
            app.PitchLabel = uilabel(app.InputsTab);
            app.PitchLabel.Text = 'Pitch';
            app.SimulatorTab = uitab(app.TabGroup);
            app.SimulatorTab.Title = 'Simulator';
            app.TimestepsEditFieldLabel = uilabel(app.SimulatorTab);
            app.TimestepsEditFieldLabel.Text = 'Time step [s]';
            app.TimestepsEditField = uieditfield(app.SimulatorTab, 'numeric');
            app.TimestepsEditField.Value = 0.05;
            app.BLADEDISCRETIZATIONLabel = uilabel(app.SimulatorTab);
            app.BLADEDISCRETIZATIONLabel.Text = 'BLADE DISCRETIZATION';
            app.NoElementsEditFieldLabel = uilabel(app.SimulatorTab);
            app.NoElementsEditFieldLabel.Text = 'No. Elements';
            app.NoElementsEditField = uieditfield(app.SimulatorTab, 'numeric');
            app.NoElementsEditField.Value = 25;
            app.ChooseSolverButtonGroup = uibuttongroup(app.SimulatorTab);
            app.ChooseSolverButtonGroup.Title = 'Choose Solver';
            app.ode23Button = uiradiobutton(app.ChooseSolverButtonGroup);
            app.ode23Button.Text = 'ode23';
            app.ode45Button = uiradiobutton(app.ChooseSolverButtonGroup);
            app.ode45Button.Text = 'ode45';
            app.ode78Button = uiradiobutton(app.ChooseSolverButtonGroup);
            app.ode78Button.Text = 'ode78';
            app.ode89Button = uiradiobutton(app.ChooseSolverButtonGroup);
            app.ode89Button.Text = 'ode89';
            app.od113Button = uiradiobutton(app.ChooseSolverButtonGroup);
            app.od113Button.Text = 'od113';
            app.Hyperlink = uihyperlink(app.ChooseSolverButtonGroup);
            app.Hyperlink.URL = 'https://www.mathworks.com/help/matlab/ordinary-differential-equations.html?s_tid=CRUX_lftnav';
            app.Hyperlink.Text = 'Source';
            app.ode15sButton = uiradiobutton(app.ChooseSolverButtonGroup);
            app.ode15sButton.Text = 'ode15s';
            app.ode23sButton = uiradiobutton(app.ChooseSolverButtonGroup);
            app.ode23sButton.Text = 'ode23s';
            app.ode23tButton = uiradiobutton(app.ChooseSolverButtonGroup);
            app.ode23tButton.Text = 'ode23t';
            app.ode23tbButton = uiradiobutton(app.ChooseSolverButtonGroup);
            app.ode23tbButton.Text = 'ode23tb';
            app.AnalyticButton = uiradiobutton(app.ChooseSolverButtonGroup);
            app.AnalyticButton.Text = 'Analytic';
            app.AnalyticButton.Value = true;
            app.RK4thButton = uiradiobutton(app.ChooseSolverButtonGroup);
            app.RK4thButton.Text = 'RK-4th';
            app.TIMEDISCRETIZATIONLabel = uilabel(app.SimulatorTab);
            app.TIMEDISCRETIZATIONLabel.Text = 'TIME DISCRETIZATION';
            app.SOLVERLabel = uilabel(app.SimulatorTab);
            app.SOLVERLabel.Text = 'SOLVER';
            app.TimeLimitsEditFieldLabel = uilabel(app.SimulatorTab);
            app.TimeLimitsEditFieldLabel.Text = 'Time Limit [s]';
            app.TimeLimitsEditField = uieditfield(app.SimulatorTab, 'numeric');
            app.TimeLimitsEditField.Value = Inf;
            app.ChooseAMButtonGroup = uibuttongroup(app.SimulatorTab);
            app.ChooseAMButtonGroup.Title = 'Choose AM';
            app.EAMButton = uiradiobutton(app.ChooseAMButtonGroup);
            app.EAMButton.Text = 'EAM';
            app.StandardAtmosphereButton = uiradiobutton(app.ChooseAMButtonGroup);
            app.StandardAtmosphereButton.Text = 'Standard Atmosphere';
            app.StandardAtmosphereButton.Value = true;
            app.Hyperlink2 = uihyperlink(app.ChooseAMButtonGroup);
            app.Hyperlink2.URL = 'https://www.grc.nasa.gov/www/k-12/airplane/atmosmet.html';
            app.Hyperlink2.Text = 'Source';
            app.Hyperlink3 = uihyperlink(app.ChooseAMButtonGroup);
            app.Hyperlink3.URL = 'https://www.mathworks.com/help/aerotbx/ug/atmosisa.html';
            app.Hyperlink3.Text = 'Source';
            app.ATMOSPHEREMODELLabel = uilabel(app.SimulatorTab);
            app.ATMOSPHEREMODELLabel.Text = 'ATMOSPHERE MODEL';
            app.INITIALIZATIONLabel = uilabel(app.SimulatorTab);
            app.INITIALIZATIONLabel.Text = 'INITIALIZATION';
            app.InitValuesforsimulationEditFieldLabel = uilabel(app.SimulatorTab);
            app.InitValuesforsimulationEditFieldLabel.Text = 'Init Values for simulation';
            app.InitValuesforsimulationEditField = uieditfield(app.SimulatorTab, 'numeric');
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ChooseDataButtonGroup = uibuttongroup(app.ResultsTab);
            app.ChooseDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseDataButtonGroupSelectionChanged, true);
            app.ChooseDataButtonGroup.Title = 'Choose Data';
            app.dF_xButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.dF_xButton.Text = 'dF_x';
            app.dF_xButton.Value = true;
            app.dF_yButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.dF_yButton.Text = 'dF_y';
            app.dF_zButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.dF_zButton.Text = 'dF_z';
            app.dtau_xButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.dtau_xButton.Text = 'dtau_x';
            app.dtau_yButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.dtau_yButton.Text = 'dtau_y';
            app.dtau_zButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.dtau_zButton.Text = 'dtau_z';
            app.dLButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.dLButton.Text = 'dL';
            app.dDButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.dDButton.Text = 'dD';
            app.v_xButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.v_xButton.Text = 'v_x';
            app.ang_vButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.ang_vButton.Text = 'ang_v';
            app.alphaButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.alphaButton.Text = 'alpha';
            app.thetaButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.thetaButton.Text = 'theta';
            app.phiButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.phiButton.Text = 'phi';
            app.total_vButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.total_vButton.Text = 'total_v';
            app.ClButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.ClButton.Text = 'Cl';
            app.CdButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.CdButton.Text = 'Cd';
            app.U_TButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.U_TButton.Text = 'U_T';
            app.U_PButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.U_PButton.Text = 'U_P';
            app.U_RButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.U_RButton.Text = 'U_R';
            app.ReButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.ReButton.Text = 'Re';
            app.MaButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.MaButton.Text = 'Ma';
            app.element_stateButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.element_stateButton.Text = 'element_state';
            app.v_yButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.v_yButton.Text = 'v_y';
            app.v_zButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.v_zButton.Text = 'v_z';
            app.PrandtlDistButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.PrandtlDistButton.Text = 'Prandtl Dist.';
            app.dF_z_prandtlButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.dF_z_prandtlButton.Text = 'dF_z_prandtl';
            app.StallAngleButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.StallAngleButton.Text = 'Stall Angle';
            app.StallModeButton = uiradiobutton(app.ChooseDataButtonGroup);
            app.StallModeButton.Text = 'Stall Mode';
            app.PositionButton = uibutton(app.ResultsTab, 'push');
            app.PositionButton.ButtonPushedFcn = createCallbackFcn(app, @PositionButtonPushed, true);
            app.PositionButton.Text = 'Position';
            app.VelocityButton = uibutton(app.ResultsTab, 'push');
            app.VelocityButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityButtonPushed, true);
            app.VelocityButton.Text = 'Velocity';
            app.AccelerationButton = uibutton(app.ResultsTab, 'push');
            app.AccelerationButton.ButtonPushedFcn = createCallbackFcn(app, @AccelerationButtonPushed, true);
            app.AccelerationButton.Text = 'Acceleration';
            app.KINEMATICSLabel = uilabel(app.ResultsTab);
            app.KINEMATICSLabel.Text = 'KINEMATICS';
            app.PositionButton_rotor = uibutton(app.ResultsTab, 'push');
            app.PositionButton_rotor.ButtonPushedFcn = createCallbackFcn(app, @PositionButton_rotorPushed, true);
            app.PositionButton_rotor.Text = 'Position';
            app.AccelerationButton_rotor = uibutton(app.ResultsTab, 'push');
            app.AccelerationButton_rotor.ButtonPushedFcn = createCallbackFcn(app, @AccelerationButton_rotorPushed, true);
            app.AccelerationButton_rotor.Text = 'Acceleration';
            app.ROTORLabel = uilabel(app.ResultsTab);
            app.ROTORLabel.Text = 'ROTOR';
            app.BLADELabel = uilabel(app.ResultsTab);
            app.BLADELabel.Text = 'BLADE';
            app.gravity_forceButton = uibutton(app.ResultsTab, 'push');
            app.gravity_forceButton.ButtonPushedFcn = createCallbackFcn(app, @gravity_forceButtonPushed, true);
            app.gravity_forceButton.Text = 'Gravity';
            app.rotor_forceButton = uibutton(app.ResultsTab, 'push');
            app.rotor_forceButton.ButtonPushedFcn = createCallbackFcn(app, @rotor_forceButtonPushed, true);
            app.rotor_forceButton.Text = 'Rotor';
            app.drag_forceButton = uibutton(app.ResultsTab, 'push');
            app.drag_forceButton.ButtonPushedFcn = createCallbackFcn(app, @drag_forceButtonPushed, true);
            app.drag_forceButton.Text = 'Cilinder Drag';
            app.FORCESLabel = uilabel(app.ResultsTab);
            app.FORCESLabel.Text = 'FORCES';
            app.total_forceButton = uibutton(app.ResultsTab, 'push');
            app.total_forceButton.ButtonPushedFcn = createCallbackFcn(app, @total_forceButtonPushed, true);
            app.total_forceButton.Text = 'Total';
            app.rotor_torque_button = uibutton(app.ResultsTab, 'push');
            app.rotor_torque_button.ButtonPushedFcn = createCallbackFcn(app, @rotor_torque_buttonPushed, true);
            app.rotor_torque_button.Text = 'Torque';
            app.Switch = uiswitch(app.ResultsTab, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.EulerAnglesButton = uibutton(app.ResultsTab, 'push');
            app.EulerAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @EulerAnglesButtonPushed, true);
            app.EulerAnglesButton.Text = 'Euler Angles';
            app.AngularvelociyButton = uibutton(app.ResultsTab, 'push');
            app.AngularvelociyButton.ButtonPushedFcn = createCallbackFcn(app, @AngularvelociyButtonPushed, true);
            app.AngularvelociyButton.Text = 'Angular velociy';
            app.AngularAccelerationButton = uibutton(app.ResultsTab, 'push');
            app.AngularAccelerationButton.ButtonPushedFcn = createCallbackFcn(app, @AngularAccelerationButtonPushed, true);
            app.AngularAccelerationButton.Text = 'Angular Acceleration';
            app.ATMOSPHERELabel = uilabel(app.ResultsTab);
            app.ATMOSPHERELabel.Text = 'ATMOSPHERE';
            app.temperature = uibutton(app.ResultsTab, 'push');
            app.temperature.ButtonPushedFcn = createCallbackFcn(app, @temperaturePushed, true);
            app.temperature.Text = 'Temperature';
            app.density = uibutton(app.ResultsTab, 'push');
            app.density.ButtonPushedFcn = createCallbackFcn(app, @densityPushed, true);
            app.density.Text = 'Density';
            app.pressure = uibutton(app.ResultsTab, 'push');
            app.pressure.ButtonPushedFcn = createCallbackFcn(app, @pressureButtonPushed, true);
            app.pressure.Text = 'Pressure';
            app.dynamicViscosity = uibutton(app.ResultsTab, 'push');
            app.dynamicViscosity.ButtonPushedFcn = createCallbackFcn(app, @dynamicViscosityButtonPushed, true);
            app.dynamicViscosity.Text = 'Dynamic Viscosity';
            app.kinematicViscosity = uibutton(app.ResultsTab, 'push');
            app.kinematicViscosity.ButtonPushedFcn = createCallbackFcn(app, @kinematicViscosityButtonPushed, true);
            app.kinematicViscosity.Text = 'Kinematic Viscosity';
            app.soundspeed = uibutton(app.ResultsTab, 'push');
            app.soundspeed.ButtonPushedFcn = createCallbackFcn(app, @soundspeedButtonPushed, true);
            app.soundspeed.Text = 'Sound Speed';
            app.TrajectoryButton = uibutton(app.ResultsTab, 'push');
            app.TrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @TrajectoryButtonPushed, true);
            app.TrajectoryButton.Text = 'Trajectory';
            app.SHAFTLabel = uilabel(app.ResultsTab);
            app.SHAFTLabel.Text = 'SHAFT';
            app.rotor_torque_button_2 = uibutton(app.ResultsTab, 'push');
            app.rotor_torque_button_2.ButtonPushedFcn = createCallbackFcn(app, @rotor_torque_button_2ButtonPushed, true);
            app.rotor_torque_button_2.Text = 'Shaft Torque';
            app.VelocityButton_rotor_2 = uibutton(app.ResultsTab, 'push');
            app.VelocityButton_rotor_2.ButtonPushedFcn = createCallbackFcn(app, @VelocityButton_rotor_2Pushed, true);
            app.VelocityButton_rotor_2.Text = 'Velocity RPM';
            app.STALLLabel = uilabel(app.ResultsTab);
            app.STALLLabel.Text = 'STALL';
            app.stall_region_button = uibutton(app.ResultsTab, 'push');
            app.stall_region_button.ButtonPushedFcn = createCallbackFcn(app, @stall_region_buttonButtonPushed, true);
            app.stall_region_button.Text = 'Stall Region';
            app.OtherTab = uitab(app.TabGroup);
            app.OtherTab.Title = 'Other';
            app.SaveFigureButton = uibutton(app.OtherTab, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.PathtosaveEditFieldLabel = uilabel(app.OtherTab);
            app.PathtosaveEditFieldLabel.Text = 'Path to save';
            app.PathtosaveEditField = uieditfield(app.OtherTab, 'text');
            app.PathtosaveEditField.Value = 'figures';
            app.NametosavefigureEditFieldLabel = uilabel(app.OtherTab);
            app.NametosavefigureEditFieldLabel.Text = 'Name to save figure';
            app.NametosavefigureEditField = uieditfield(app.OtherTab, 'text');
            app.NametosavefigureEditField.Value = 'example';
            app.ChoosethefiletypetosavepictureButtonGroup = uibuttongroup(app.OtherTab);
            app.ChoosethefiletypetosavepictureButtonGroup.Title = 'Choose the file type to save picture';
            app.pngButton = uiradiobutton(app.ChoosethefiletypetosavepictureButtonGroup);
            app.pngButton.Text = 'png';
            app.pngButton.Value = true;
            app.jpgButton = uiradiobutton(app.ChoosethefiletypetosavepictureButtonGroup);
            app.jpgButton.Text = 'jpg';
            app.epsButton = uiradiobutton(app.ChoosethefiletypetosavepictureButtonGroup);
            app.epsButton.Text = 'eps';
            app.pdfButton = uiradiobutton(app.ChoosethefiletypetosavepictureButtonGroup);
            app.pdfButton.Text = 'pdf';
            app.SAVEPLOTLabel = uilabel(app.OtherTab);
            app.SAVEPLOTLabel.Text = 'SAVE PLOT';
            app.AERODASCONFIGURATIONLabel = uilabel(app.OtherTab);
            app.AERODASCONFIGURATIONLabel.Text = 'AERODAS CONFIGURATION';
            app.OutputfileEditFieldLabel = uilabel(app.OtherTab);
            app.OutputfileEditFieldLabel.Text = 'Output file';
            app.OutputfileEditField = uieditfield(app.OtherTab, 'text');
            app.CommandlineoutputCheckBox = uicheckbox(app.OtherTab);
            app.CommandlineoutputCheckBox.Text = 'Command line output';
            app.SAVEDATALabel = uilabel(app.OtherTab);
            app.SAVEDATALabel.Text = 'SAVE DATA';
            app.FilenametosaveEditFieldLabel = uilabel(app.OtherTab);
            app.FilenametosaveEditFieldLabel.Text = 'File name to save';
            app.FilenametosaveEditField = uieditfield(app.OtherTab, 'text');
            app.FilenametosaveEditField.Value = 'data';
            app.SavedataButton = uibutton(app.OtherTab, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.SaveallButton = uibutton(app.OtherTab, 'push');
            app.SaveallButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallButtonPushed, true);
            app.SaveallButton.Text = 'Save all';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.LOGEditFieldLabel = uilabel(app.UIFigure);
            app.LOGEditFieldLabel.Text = 'LOG';
            app.LOGEditField = uieditfield(app.UIFigure, 'text');
            app.TimecontrollerLabel = uilabel(app.UIFigure);
            app.TimecontrollerLabel.Text = 'Time controller';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PARATOR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end