classdef analysis2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        tcEditField                    matlab.ui.control.NumericEditField
        tcEditFieldLabel               matlab.ui.control.Label
        GenerateDataButton             matlab.ui.control.Button
        Hyperlink                      matlab.ui.control.Hyperlink
        NoPEditField                   matlab.ui.control.NumericEditField
        NoPEditFieldLabel              matlab.ui.control.Label
        scaleDropDown                  matlab.ui.control.DropDown
        scaleDropDownLabel             matlab.ui.control.Label
        AerodynamicModelDropDown       matlab.ui.control.DropDown
        AerodynamicModelDropDownLabel  matlab.ui.control.Label
        AnalysisTypeDropDown           matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel      matlab.ui.control.Label
        ReEditField                    matlab.ui.control.NumericEditField
        ReEditField_2Label             matlab.ui.control.Label
        EnableCheckBox_EMI_alpha       matlab.ui.control.CheckBox
        dMaEditField                   matlab.ui.control.NumericEditField
        dMaEditFieldLabel              matlab.ui.control.Label
        MaEditField                    matlab.ui.control.NumericEditField
        MaEditFieldLabel               matlab.ui.control.Label
        MachNoLabel                    matlab.ui.control.Label
        PolarAnalysisLabel             matlab.ui.control.Label
        MinEditField_Ma                matlab.ui.control.NumericEditField
        MinEditField_3Label_2          matlab.ui.control.Label
        MaxEditField_Ma                matlab.ui.control.NumericEditField
        MaxEditField_2Label_2          matlab.ui.control.Label
        MinEditField_Re                matlab.ui.control.NumericEditField
        MinEditField_3Label            matlab.ui.control.Label
        MaxEditField_Re                matlab.ui.control.NumericEditField
        dalphaEditField                matlab.ui.control.NumericEditField
        Lamp                           matlab.ui.control.Lamp
        dalphaEditFieldLabel           matlab.ui.control.Label
        MaxEditField_2Label            matlab.ui.control.Label
        EnableCheckBox_EMI_Ma          matlab.ui.control.CheckBox
        EnableCheckBox_EMI_Re          matlab.ui.control.CheckBox
        AddButton                      matlab.ui.control.Button
        NewAirfoilEditField            matlab.ui.control.EditField
        NewAirfoilEditFieldLabel       matlab.ui.control.Label
        AnalysisButton                 matlab.ui.control.Button
        AirfoilDropDown                matlab.ui.control.DropDown
        AirfoilDropDownLabel           matlab.ui.control.Label
        alphaEditField                 matlab.ui.control.NumericEditField
        alphaEditFieldLabel            matlab.ui.control.Label
        ReynoldNoLabel                 matlab.ui.control.Label
        MinEditField_alpha             matlab.ui.control.NumericEditField
        MinEditField_2Label            matlab.ui.control.Label
        AngleofAttackLabel             matlab.ui.control.Label
        MaxEditField_alpha             matlab.ui.control.NumericEditField
        MaxLabel                       matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        DAirfoilAnalysisLabel          matlab.ui.control.Label
        UI_axes_airfoil                matlab.ui.control.UIAxes
        UI_CM                          matlab.ui.control.UIAxes
        UI_CD                          matlab.ui.control.UIAxes
        UI_CL                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        astral_mainapp

        airfoils_directory = "./airfoils/coord/"

        selectedAirfoil = 'n0012'

        toGenerate_Airfoil_name = ''

        aerodynamicModel  = 'Hybrid'
        analysisType = 'Alpha variation'

        alpha_EMI = true
        alpha_value = 8
        alpha_step = 0.5
        alpha_min = -5
        alpha_max = 12

        Re_EMI = false
        Re_value = 1e6
        Re_no_points = 5
        Re_scale = 'Linear'
        Re_min = 1e4
        Re_max = 1e6

        Ma_EMI = false
        Ma_value = 0
        Ma_step = 0.1
        Ma_min = 0
        Ma_max = 0.5

        alpha = [];
        Re = [];
        Ma = [];

        CL = [];
        CD = [];
        CM = [];
        conf = [];

        ll_alpha = 0;
        ll_Re = 0;
        ll_Ma = 0;

        dataGenerated = false;

    end
    
    methods (Access = private)
        
        function errorMessageBox(app, error_msg, error_type)
            uialert(app.UIFigure, error_msg,error_type);
        end


        function loadAirfoil_plot(app, airfoil_name)

            file_name = airfoil_name + ".dat";

            coord_file = app.airfoils_directory + file_name;

            if ~exist(coord_file)
                error_msg = "File " + file_name + "does not exists in" + app.airfoils_directory;
                errorMessageBox(app, error_msg, "File does not exists")
                return;
            end

            read_coords = readmatrix(coord_file);
            coords_airfoil = read_coords(2:end, :);

            plot(app.UI_axes_airfoil, coords_airfoil(:, 1), coords_airfoil(:, 2), 'k', 'LineWidth', 1.5);
            grid(app.UI_axes_airfoil, "on")
            axis(app.UI_axes_airfoil, 'equal') 
            xlim(app.UI_axes_airfoil, [0 1])
        end        
        
        function loadAirfoil_list(app)
            % Specify the folder path
            folderPath = app.airfoils_directory; % Replace this with your folder path
            
            % Get all file names with .dat extension
            filePattern = fullfile(folderPath, '*.dat'); % Pattern to match .dat files
            files = dir(filePattern);
            
            % Extract and clean file names
            fileNames = {files.name}; % Get the full file names
            fileNames = erase(fileNames, '.dat'); % Remove .dat extension
                        
            % If you want to use these names in a dropdown menu in App Designer
            app.AirfoilDropDown.Items = fileNames; % Replace 'FileDropdown' with your dropdown component

        end

        function generate_dataPlot(app)

            if ~app.dataGenerated
                errorMessageBox(app, "Polar data is not available for plot. Generate data first", "Invalid Data")
                return;
            end

            cla(app.UI_CL);
            cla(app.UI_CD);
            cla(app.UI_CM);

            if strcmp(app.analysisType, "Alpha variation")
        
                % Plot CL vs Alpha
                hold(app.UI_CL, 'on'); grid(app.UI_CL, 'on');
                for i = 1:app.ll_Ma
                    for j = 1:app.ll_Re
                        CL_VEC = squeeze(app.CL(i, j, :));
                        plot(app.UI_CL, app.alpha, CL_VEC);
                    end
                end
                xlabel(app.UI_CL, '\alpha (deg)');
                ylabel(app.UI_CL, 'C_L');
                hold(app.UI_CL, 'off');
        
                % Plot CD vs Alpha
                hold(app.UI_CD, 'on'); grid(app.UI_CD, 'on');
                for i = 1:app.ll_Ma
                    for j = 1:app.ll_Re
                        CD_VEC = squeeze(app.CD(i, j, :));
                        plot(app.UI_CD, app.alpha, CD_VEC);
                    end
                end
                xlabel(app.UI_CD, '\alpha (deg)');
                ylabel(app.UI_CD, 'C_D');
                hold(app.UI_CD, 'off');
        
                % Plot CM vs Alpha
                hold(app.UI_CM, 'on'); grid(app.UI_CM, 'on');
                for i = 1:app.ll_Ma
                    for j = 1:app.ll_Re
                        CM_VEC = squeeze(app.CM(i, j, :));
                        plot(app.UI_CM, app.alpha, CM_VEC);
                    end
                end
                xlabel(app.UI_CM, '\alpha (deg)');
                ylabel(app.UI_CM, 'C_M');
                hold(app.UI_CM, 'off');
        
            elseif strcmp(app.analysisType, "Re variation")
        
                % Plot CL vs Re
                hold(app.UI_CL, 'on'); grid(app.UI_CL, 'on');
                for i = 1:app.ll_Ma
                    for k = 1:app.ll_alpha
                        CL_VEC = squeeze(app.CL(i, :, k));
                        plot(app.UI_CL, app.Re, CL_VEC);
                    end
                end
                xlabel(app.UI_CL, 'Re');
                ylabel(app.UI_CL, 'C_L');
                hold(app.UI_CL, 'off');
        
                % Plot CD vs Re
                hold(app.UI_CD, 'on'); grid(app.UI_CD, 'on');
                for i = 1:app.ll_Ma
                    for k = 1:app.ll_alpha
                        CD_VEC = squeeze(app.CD(i, :, k));
                        plot(app.UI_CD, app.Re, CD_VEC);
                    end
                end
                xlabel(app.UI_CD, 'Re');
                ylabel(app.UI_CD, 'C_D');
                hold(app.UI_CD, 'off');
        
                % Plot CM vs Re
                hold(app.UI_CM, 'on'); grid(app.UI_CM, 'on');
                for i = 1:app.ll_Ma
                    for k = 1:app.ll_alpha
                        CM_VEC = squeeze(app.CM(i, :, k));
                        plot(app.UI_CM, app.Re, CM_VEC);
                    end
                end
                xlabel(app.UI_CM, 'Re');
                ylabel(app.UI_CM, 'C_M');
                hold(app.UI_CM, 'off');
        
            elseif strcmp(app.analysisType, "Ma variation")
        
                % Plot CL vs Ma
                hold(app.UI_CL, 'on'); grid(app.UI_CL, 'on');
                for j = 1:app.ll_Re
                    for k = 1:app.ll_alpha
                        CL_VEC = squeeze(app.CL(:, j, k));
                        plot(app.UI_CL, app.Ma, CL_VEC);
                    end
                end
                xlabel(app.UI_CL, 'Ma');
                ylabel(app.UI_CL, 'C_L');
                hold(app.UI_CL, 'off');
        
                % Plot CD vs Ma
                hold(app.UI_CD, 'on'); grid(app.UI_CD, 'on');
                for j = 1:app.ll_Re
                    for k = 1:app.ll_alpha
                        CD_VEC = squeeze(app.CD(:, j, k));
                        plot(app.UI_CD, app.Ma, CD_VEC);
                    end
                end
                xlabel(app.UI_CD, 'Ma');
                ylabel(app.UI_CD, 'C_D');
                hold(app.UI_CD, 'off');
        
                % Plot CM vs Ma
                hold(app.UI_CM, 'on'); grid(app.UI_CM, 'on');
                for j = 1:app.ll_Re
                    for k = 1:app.ll_alpha
                        CM_VEC = squeeze(app.CM(:, j, k));
                        plot(app.UI_CM, app.Ma, CM_VEC);
                    end
                end
                xlabel(app.UI_CM, 'Ma');
                ylabel(app.UI_CM, 'C_M');
                hold(app.UI_CM, 'off');
        
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, astral_mainapp)
            
            clc

            app.astral_mainapp = astral_mainapp;

            fprintf("-> 2D Airfoil Aerodynamic analysis app opened\n")

            loadAirfoil_list(app)
            loadAirfoil_plot(app, app.selectedAirfoil)

        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            
            % gerar os valores de alpha
            if app.alpha_EMI == true
                app.alpha = app.alpha_min:app.alpha_step:app.alpha_max;
            else
                app.alpha = app.alpha_value;
            end

            % gerar os valores de Re
            if app.Re_EMI == true
                if strcmp(app.Re_scale, 'Linear')
                    app.Re = linspace(app.Re_min, app.Re_max, app.Re_no_points);
                elseif strcmp(app.Re_scale, 'Log')
                    app.Re = logspace(log10(app.Re_min), log10(app.Re_max), app.Re_no_points);
                end
            else
                app.Re = app.Re_value;
            end

            % gerar os valores de Ma
            if app.Ma_EMI == true
                app.Ma = app.Ma_min:app.Ma_step:app.Ma_max;
            else
                app.Ma = app.Ma_value;
            end

            disp(app.alpha)
            disp(app.Re)
            disp(app.Ma)

            app.ll_alpha = length(app.alpha);
            app.ll_Re = length(app.Re);
            app.ll_Ma = length(app.Ma);

            app.CL = [];
            app.CD = [];
            app.CM = [];
            app.conf = [];

            app.CL = zeros(app.ll_Ma, app.ll_Re, app.ll_alpha);
            app.CD = zeros(app.ll_Ma, app.ll_Re, app.ll_alpha);
            app.CM = zeros(app.ll_Ma, app.ll_Re, app.ll_alpha);
            app.conf = zeros(app.ll_Ma, app.ll_Re, app.ll_alpha);

            
            total_cases = app.ll_Ma * app.ll_Re * app.ll_alpha;
            current_case = 0;

            app.Lamp.Color = 'red';
            drawnow;

            for i= 1:app.ll_Ma
                for j = 1:app.ll_Re
                    for k = 1:app.ll_alpha

                        fprintf("[%s] i = %d | k = %d | k = %d \n", app.aerodynamicModel, i, j, k);
                        if strcmp(app.aerodynamicModel, 'NeuralFoil')
                            
                            [app.CL(i,j,k), app.CD(i,j,k), app.CM(i,j,k), app.conf(i,j,k)] = NeuralFoil(app.selectedAirfoil, app.Re(j), app.Ma(i), app.alpha(k));
                       
                        elseif strcmp(app.aerodynamicModel, 'Hybrid')

                            disp("aqui")

                            airfoil_name_toLoad = app.selectedAirfoil;
                            airfoil_data_file = "./airfoils/data/" + airfoil_name_toLoad + ".mat";
                            airfoil = load(airfoil_data_file).airfoil;

                            app.CM(i,j,k) = 0;
                            app.conf(i,j,k) = 0;

                            [app.CL(i,j,k), app.CD(i,j,k), ~] = AerodynamicModel(app.alpha(k), app.Re(j), airfoil.data, false);


                        elseif strcmp(app.aerodynamicModel, 'XFOIL')
                            %errorMessageBox(app, "XFOIL NOT AVAILABLE", "Error")                            
                        end

                        current_case = current_case + 1;
                        
                        if current_case == floor(total_cases / 2)
                            app.Lamp.Color = 'yellow';
                            drawnow;
                        end

                    end
                end
            end    

            app.Lamp.Color = 'green';
            drawnow;

            app.dataGenerated = true;

            generate_dataPlot(app)


        end

        % Value changed function: AnalysisTypeDropDown
        function AnalysisTypeDropDownValueChanged(app, event)
            app.analysisType = app.AnalysisTypeDropDown.Value;

            fprintf("Selected analysis type: %s\n", app.analysisType)

            if strcmp(app.analysisType, "Alpha variation")

                app.EnableCheckBox_EMI_alpha.Enable = "off";
                app.EnableCheckBox_EMI_alpha.Value = true;
                app.alpha_EMI = true;

                app.EnableCheckBox_EMI_Re.Enable = "on";
                app.EnableCheckBox_EMI_Re.Value = false;
                app.Re_EMI = false;


                app.EnableCheckBox_EMI_Ma.Enable = "on";
                app.EnableCheckBox_EMI_Ma.Value = false;
                app.Ma_EMI = false;
                
            elseif strcmp(app.analysisType, "Re variation")

                app.EnableCheckBox_EMI_alpha.Enable = "on";
                app.EnableCheckBox_EMI_alpha.Value = false;
                app.alpha_EMI = false;

                app.EnableCheckBox_EMI_Re.Enable = "off";
                app.EnableCheckBox_EMI_Re.Value = true;     
                app.Re_EMI = true;

                app.EnableCheckBox_EMI_Ma.Enable = "on";
                app.EnableCheckBox_EMI_Ma.Value = false;
                app.Ma_EMI = false;

            elseif strcmp(app.analysisType, "Ma variation")

                app.EnableCheckBox_EMI_alpha.Enable = "on";
                app.EnableCheckBox_EMI_alpha.Value = false;
                app.alpha_EMI = false;

                app.EnableCheckBox_EMI_Re.Enable = "on";
                app.EnableCheckBox_EMI_Re.Value = false;
                app.Re_EMI = false;


                app.EnableCheckBox_EMI_Ma.Enable = "off";
                app.EnableCheckBox_EMI_Ma.Value = true;
                app.Ma_EMI = true;
            
            end

        end

        % Value changed function: AerodynamicModelDropDown
        function AerodynamicModelDropDownValueChanged(app, event)
            app.aerodynamicModel = app.AerodynamicModelDropDown.Value;

            fprintf("Selected aerodynamic Model: %s\n", app.aerodynamicModel)
            
        end

        % Value changed function: AirfoilDropDown
        function AirfoilDropDownValueChanged(app, event)
            app.selectedAirfoil = app.AirfoilDropDown.Value;

            fprintf("Selected airofoil: %s\n", app.selectedAirfoil)
            
            loadAirfoil_plot(app, app.selectedAirfoil)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
            if isempty(app.toGenerate_Airfoil_name)
                    errorMessageBox(app, "Airfoil name is empty", "Invalid Input")
                return;
            end

            base_url = "https://m-selig.ae.illinois.edu/ads/coord/";            
            file_type = ".dat";            
            airfoil_directory = "./airfoils/coord/";
            
            save_file_directory = airfoil_directory + app.toGenerate_Airfoil_name + file_type;
            
            full_url = base_url + app.toGenerate_Airfoil_name + file_type;

            websave(save_file_directory, full_url);

            app.NewAirfoilEditField.Value = "";

            app.selectedAirfoil = app.toGenerate_Airfoil_name;         

            loadAirfoil_plot(app, app.toGenerate_Airfoil_name)
            loadAirfoil_list(app)

            app.AirfoilDropDown.Value = app.selectedAirfoil;

        end

        % Value changed function: NewAirfoilEditField
        function NewAirfoilEditFieldValueChanged(app, event)
            app.toGenerate_Airfoil_name = app.NewAirfoilEditField.Value;            
        end

        % Value changed function: EnableCheckBox_EMI_alpha
        function EnableCheckBox_EMI_alphaValueChanged(app, event)
            app.alpha_EMI = app.EnableCheckBox_EMI_alpha.Value;
            
        end

        % Value changed function: EnableCheckBox_EMI_Re
        function EnableCheckBox_EMI_ReValueChanged(app, event)
            app.Re_EMI = app.EnableCheckBox_EMI_Re.Value;
            
        end

        % Value changed function: EnableCheckBox_EMI_Ma
        function EnableCheckBox_EMI_MaValueChanged(app, event)
            app.Ma_EMI = app.EnableCheckBox_EMI_Ma.Value;
            
        end

        % Value changed function: alphaEditField
        function alphaEditFieldValueChanged(app, event)
            alpha_value_prev = app.alphaEditField.Value;

            if alpha_value_prev < -180
                app.alphaEditField.Value = -180;
                app.alpha_value = -180;
                errorMessageBox(app, "Alpha valeu outside boundaries [-180, 180]", "Invalid Input")
                return
            elseif alpha_value_prev > 180
                app.alphaEditField.Value = 180;
                app.alpha_value = 180;
                errorMessageBox(app, "Alpha valeu outside boundaries [-180, 180]", "Invalid Input")
                return
            end

            app.alpha_value = alpha_value_prev;
            
        end

        % Value changed function: dalphaEditField
        function dalphaEditFieldValueChanged(app, event)
            alpha_step_prev = app.dalphaEditField.Value;
            
            if alpha_step_prev < 1e-5
                errorMessageBox(app, "Alpha step should be higher than 1e-5", "Invalid Input")
                app.alpha_step = 1e-5;
                return
            end

            app.alpha_step = alpha_step_prev;


        end

        % Value changed function: MinEditField_alpha
        function MinEditField_alphaValueChanged(app, event)
            alpha_min_prev = app.MinEditField_alpha.Value;

            if alpha_min_prev > app.alpha_max
                app.MinEditField_alpha.Value = -180;
                app.alpha_min = -180;
                errorMessageBox(app, "Alpha minimum value should be lower than maximum value", "Invalid Input")
                return
            elseif alpha_min_prev < -180
                app.MinEditField_alpha.Value = -180;
                app.alpha_min = -180;
                errorMessageBox(app, "Alpha minimum valeu outside boundaries [-180, 180]", "Invalid Input")
                return
            elseif alpha_min_prev > 180
                app.MinEditField_alpha.Value = 180;
                app.alpha_min = 180;
                errorMessageBox(app, "Alpha minimum valeu outside boundaries [-180, 180]", "Invalid Input")
                return
            end

            app.alpha_min = alpha_min_prev;
            
        end

        % Value changed function: MaxEditField_alpha
        function MaxEditField_alphaValueChanged(app, event)
            alpha_max_prev = app.MaxEditField_alpha.Value;

            if alpha_max_prev < app.alpha_min
                app.MaxEditField_alpha.Value = 180;
                app.alpha_max = 180;
                errorMessageBox(app, "Alpha maximum value should be higher than minimun value", "Invalid Input")
                return
            elseif alpha_max_prev < -180
                app.MaxEditField_alpha.Value = -180;
                app.alpha_max = -180;
                errorMessageBox(app, "Alpha valeu outside boundaries [-180, 180]", "Invalid Input")
                return
            elseif alpha_max_prev > 180
                app.MaxEditField_alpha.Value = 180;
                app.alpha_max = 180;
                errorMessageBox(app, "Alpha aleu outside boundaries [-180, 180]", "Invalid Input")
                return
            end

            app.alpha_max = alpha_max_prev;
            
        end

        % Value changed function: ReEditField
        function ReEditFieldValueChanged(app, event)
            value = app.ReEditField.Value;

            if value < 1e2
                app.Re_value = 1e2;
                app.ReEditField.Value = 1e2;
                errorMessageBox(app, "Re valeu outside boundaries [1e2, 1e10]", "Invalid Input")
                return
            elseif value > 1e10
                app.ReEditField.Value = 1e10;
                app.Re_value = 1e10;
                errorMessageBox(app, "Re valeu outside boundaries [1e2, 1e10]", "Invalid Input")
                return
            end

            app.Re_value = value;
            
        end

        % Value changed function: scaleDropDown
        function scaleDropDownValueChanged(app, event)
            app.Re_scale = app.scaleDropDown.Value;
            
        end

        % Value changed function: NoPEditField
        function NoPEditFieldValueChanged(app, event)
            value = app.NoPEditField.Value;

            if value < 1
                app.NoPEditField.Value = 1;
                app.Re_no_points = 1;
                errorMessageBox(app, "Re operation no. of points should be higher than 1", "Invalid Input")
                return
            end 
            
            app.Re_no_points = value;
        end

        % Value changed function: MinEditField_Re
        function MinEditField_ReValueChanged(app, event)
            value = app.MinEditField_Re.Value;
            
            if value > app.Re_max
                app.MinEditField_Re.Value = 1e2;
                app.Re_min = 1e2;
                errorMessageBox(app, "Re minimum value should be lower than maximum value", "Invalid Input")
                return
            elseif value < 1e2 
                app.MinEditField_Re.Value = 1e2;
                app.Re_min = 1e2;
                errorMessageBox(app, "Re valeu outside boundaries [1e2, 1e10]", "Invalid Input")
                return
            elseif value > 1e10
                app.MinEditField_Re.Value = 1e10;
                app.Re_min = 1e10;
                errorMessageBox(app, "Re valeu outside boundaries [1e2, 1e10]", "Invalid Input")
                return
            end

            app.Re_min = value;
        end

        % Value changed function: MaxEditField_Re
        function MaxEditField_ReValueChanged(app, event)
            value = app.MaxEditField_Re.Value;
            
            if value < app.Re_min
                app.MaxEditField_Re.Value = 1e10;
                app.Re_max = 1e10;
                errorMessageBox(app, "Re maximum value should be higher than minimum value", "Invalid Input")
                return
            elseif value < 1e2 
                app.MaxEditField_Re.Value = 1e2;
                app.Re_max = 1e2;
                errorMessageBox(app, "Re valeu outside boundaries [1e2, 1e10]", "Invalid Input")
                return
            elseif value > 1e10
                app.MaxEditField_Re.Value = 1e10;
                app.Re_max = 1e10;
                errorMessageBox(app, "Re valeu outside boundaries [1e2, 1e10]", "Invalid Input")
                return
            end

            app.Re_max = value;
        end

        % Value changed function: MaEditField
        function MaEditFieldValueChanged(app, event)
            value = app.MaEditField.Value;

            if value < 0
                app.MaEditField.Value = 0;
                app.Ma_value = 0;
                errorMessageBox(app, "Ma valeu outside boundaries [0, 3]", "Invalid Input")
                return
            elseif value > 3
                app.MaEditField.Value = 3;
                app.Ma_value = 3;
                errorMessageBox(app, "Ma valeu outside boundaries [0, 3]", "Invalid Input")
                return
            end

            app.Ma_value = value;
            
        end

        % Value changed function: dMaEditField
        function dMaEditFieldValueChanged(app, event)
            value = app.dMaEditField.Value;
            
            if value < 1e-3
                app.dMaEditField.Value = 1e-3;
                app.Ma_step = 1e-3;
                errorMessageBox(app, "Alpha step should be higher than 1e-3", "Invalid Input")
                return
            end

            app.Ma_step = value;
            
        end

        % Value changed function: MinEditField_Ma
        function MinEditField_MaValueChanged(app, event)
            value = app.MinEditField_Ma.Value;

            if value > app.Ma_max
                app.MinEditField_Ma.Value = 0;
                app.Ma_min = 0;
                errorMessageBox(app, "Ma minimum value should be lower than maximum value", "Invalid Input")
                return
            elseif value < 0 
                app.MinEditField_Ma.Value = 0;
                app.Ma_min = 0;
                errorMessageBox(app, "Ma valeu outside boundaries [0, 3]", "Invalid Input")
                return
            elseif value > 3
                app.MinEditField_Ma.Value = 3;
                app.Ma_min = 3;
                errorMessageBox(app, "Ma valeu outside boundaries [0, 3]", "Invalid Input")
                return
            end

            app.Ma_min = value;
        end

        % Value changed function: MaxEditField_Ma
        function MaxEditField_MaValueChanged(app, event)
            value = app.MaxEditField_Ma.Value;
            
            if value < app.Ma_min
                app.MaxEditField_Ma.Value = 0;
                app.Ma_max = 0;
                errorMessageBox(app, "Ma minimum value should be lower than maximum value", "Invalid Input")
                return
            elseif value < 0 
                app.MaxEditField_Ma.Value = 0;
                app.Ma_max = 0;
                errorMessageBox(app, "Ma valeu outside boundaries [0, 3]", "Invalid Input")
                return
            elseif value > 3
                app.MaxEditField_Ma.Value = 3;
                app.Ma_max = 3;
                errorMessageBox(app, "Ma valeu outside boundaries [0, 3]", "Invalid Input")
                return
            end

            app.Ma_max = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            if isvalid(app.astral_mainapp)
                app.astral_mainapp.DAEROButton.Enable = "on";
                
                app.astral_mainapp.init_var(app.astral_mainapp)
                app.astral_mainapp.init_app(app.astral_mainapp)

            end


            delete(app)
            
        end

        % Button pushed function: GenerateDataButton
        function GenerateDataButtonPushed(app, event)
            
            fprintf(">> Generating simulation data to %s\n", app.selectedAirfoil)
            
            generate_data_aeromodel(app.selectedAirfoil, app.tcEditField.Value)

            loadAirfoil_list(app.astral_mainapp, app.astral_mainapp.airfoils_data_directory)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UI_CL = uiaxes(app.UIFigure);
            title(app.UI_CL, 'Lift Coeficient')
            xlabel(app.UI_CL, '\alpha [º]')
            ylabel(app.UI_CL, 'C_L')
            app.UI_CD = uiaxes(app.UIFigure);
            title(app.UI_CD, 'Drag Coefficient')
            xlabel(app.UI_CD, '\alpha [º]')
            ylabel(app.UI_CD, 'C_D')
            zlabel(app.UI_CD, 'Z')
            app.UI_CM = uiaxes(app.UIFigure);
            title(app.UI_CM, 'Pitch Moment Coeficient')
            xlabel(app.UI_CM, '\alpha [º]')
            ylabel(app.UI_CM, 'C_M')
            app.UI_axes_airfoil = uiaxes(app.UIFigure);
            title(app.UI_axes_airfoil, 'Airfoil')
            xlabel(app.UI_axes_airfoil, 'x/c')
            ylabel(app.UI_axes_airfoil, 'y/c')
            app.DAirfoilAnalysisLabel = uilabel(app.UIFigure);
            app.DAirfoilAnalysisLabel.Text = '2D Airfoil Analysis';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '__________________________';
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Text = 'Max.';
            app.MaxEditField_alpha = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField_alpha.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_alphaValueChanged, true);
            app.MaxEditField_alpha.Value = 12;
            app.AngleofAttackLabel = uilabel(app.UIFigure);
            app.AngleofAttackLabel.Text = 'Angle of Attack';
            app.MinEditField_2Label = uilabel(app.UIFigure);
            app.MinEditField_2Label.Text = 'Min.';
            app.MinEditField_alpha = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField_alpha.ValueChangedFcn = createCallbackFcn(app, @MinEditField_alphaValueChanged, true);
            app.MinEditField_alpha.Value = -5;
            app.ReynoldNoLabel = uilabel(app.UIFigure);
            app.ReynoldNoLabel.Text = 'Reynold No.';
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Interpreter = 'latex';
            app.alphaEditFieldLabel.Text = '\alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged, true);
            app.alphaEditField.Value = 8;
            app.AirfoilDropDownLabel = uilabel(app.UIFigure);
            app.AirfoilDropDownLabel.Text = 'Airfoil';
            app.AirfoilDropDown = uidropdown(app.UIFigure);
            app.AirfoilDropDown.Items = {'n0012'};
            app.AirfoilDropDown.ValueChangedFcn = createCallbackFcn(app, @AirfoilDropDownValueChanged, true);
            app.AirfoilDropDown.Value = 'n0012';
            app.AnalysisButton = uibutton(app.UIFigure, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.NewAirfoilEditFieldLabel = uilabel(app.UIFigure);
            app.NewAirfoilEditFieldLabel.Text = 'New Airfoil';
            app.NewAirfoilEditField = uieditfield(app.UIFigure, 'text');
            app.NewAirfoilEditField.ValueChangedFcn = createCallbackFcn(app, @NewAirfoilEditFieldValueChanged, true);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.EnableCheckBox_EMI_Re = uicheckbox(app.UIFigure);
            app.EnableCheckBox_EMI_Re.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBox_EMI_ReValueChanged, true);
            app.EnableCheckBox_EMI_Re.Text = 'EMI';
            app.EnableCheckBox_EMI_Ma = uicheckbox(app.UIFigure);
            app.EnableCheckBox_EMI_Ma.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBox_EMI_MaValueChanged, true);
            app.EnableCheckBox_EMI_Ma.Text = 'EMI';
            app.MaxEditField_2Label = uilabel(app.UIFigure);
            app.MaxEditField_2Label.Text = 'Max.';
            app.dalphaEditFieldLabel = uilabel(app.UIFigure);
            app.dalphaEditFieldLabel.Interpreter = 'latex';
            app.dalphaEditFieldLabel.Text = 'd\alpha';
            app.Lamp = uilamp(app.UIFigure);
            app.dalphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.dalphaEditField.ValueChangedFcn = createCallbackFcn(app, @dalphaEditFieldValueChanged, true);
            app.dalphaEditField.Value = 0.5;
            app.MaxEditField_Re = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField_Re.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_ReValueChanged, true);
            app.MaxEditField_Re.Value = 1000000;
            app.MinEditField_3Label = uilabel(app.UIFigure);
            app.MinEditField_3Label.Text = 'Min.';
            app.MinEditField_Re = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField_Re.ValueChangedFcn = createCallbackFcn(app, @MinEditField_ReValueChanged, true);
            app.MinEditField_Re.Value = 10000;
            app.MaxEditField_2Label_2 = uilabel(app.UIFigure);
            app.MaxEditField_2Label_2.Text = 'Max.';
            app.MaxEditField_Ma = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField_Ma.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_MaValueChanged, true);
            app.MaxEditField_Ma.Value = 0.5;
            app.MinEditField_3Label_2 = uilabel(app.UIFigure);
            app.MinEditField_3Label_2.Text = 'Min.';
            app.MinEditField_Ma = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField_Ma.ValueChangedFcn = createCallbackFcn(app, @MinEditField_MaValueChanged, true);
            app.PolarAnalysisLabel = uilabel(app.UIFigure);
            app.PolarAnalysisLabel.Text = ' Polar Analysis';
            app.MachNoLabel = uilabel(app.UIFigure);
            app.MachNoLabel.Text = 'Mach No.';
            app.MaEditFieldLabel = uilabel(app.UIFigure);
            app.MaEditFieldLabel.Text = 'Ma';
            app.MaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaEditField.ValueChangedFcn = createCallbackFcn(app, @MaEditFieldValueChanged, true);
            app.dMaEditFieldLabel = uilabel(app.UIFigure);
            app.dMaEditFieldLabel.Text = 'dMa';
            app.dMaEditField = uieditfield(app.UIFigure, 'numeric');
            app.dMaEditField.ValueChangedFcn = createCallbackFcn(app, @dMaEditFieldValueChanged, true);
            app.dMaEditField.Value = 0.1;
            app.EnableCheckBox_EMI_alpha = uicheckbox(app.UIFigure);
            app.EnableCheckBox_EMI_alpha.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBox_EMI_alphaValueChanged, true);
            app.EnableCheckBox_EMI_alpha.Enable = 'off';
            app.EnableCheckBox_EMI_alpha.Text = 'EMI';
            app.EnableCheckBox_EMI_alpha.Value = true;
            app.ReEditField_2Label = uilabel(app.UIFigure);
            app.ReEditField_2Label.Text = 'Re';
            app.ReEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReEditField.ValueChangedFcn = createCallbackFcn(app, @ReEditFieldValueChanged, true);
            app.ReEditField.Value = 1000000;
            app.AnalysisTypeDropDownLabel = uilabel(app.UIFigure);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.UIFigure);
            app.AnalysisTypeDropDown.Items = {'Alpha variation', 'Re variation', 'Ma variation'};
            app.AnalysisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropDownValueChanged, true);
            app.AnalysisTypeDropDown.Value = 'Alpha variation';
            app.AerodynamicModelDropDownLabel = uilabel(app.UIFigure);
            app.AerodynamicModelDropDownLabel.Text = 'Aerodynamic Model';
            app.AerodynamicModelDropDown = uidropdown(app.UIFigure);
            app.AerodynamicModelDropDown.Items = {'Hybrid', 'NeuralFoil', 'XFOIL'};
            app.AerodynamicModelDropDown.ValueChangedFcn = createCallbackFcn(app, @AerodynamicModelDropDownValueChanged, true);
            app.AerodynamicModelDropDown.Value = 'Hybrid';
            app.scaleDropDownLabel = uilabel(app.UIFigure);
            app.scaleDropDownLabel.Text = 'scale';
            app.scaleDropDown = uidropdown(app.UIFigure);
            app.scaleDropDown.Items = {'Linear', 'Log'};
            app.scaleDropDown.ValueChangedFcn = createCallbackFcn(app, @scaleDropDownValueChanged, true);
            app.scaleDropDown.Value = 'Linear';
            app.NoPEditFieldLabel = uilabel(app.UIFigure);
            app.NoPEditFieldLabel.Text = 'No. P.';
            app.NoPEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoPEditField.ValueChangedFcn = createCallbackFcn(app, @NoPEditFieldValueChanged, true);
            app.NoPEditField.Value = 5;
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.Text = 'How';
            app.GenerateDataButton = uibutton(app.UIFigure, 'push');
            app.GenerateDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDataButtonPushed, true);
            app.GenerateDataButton.Text = 'Generate Data';
            app.tcEditFieldLabel = uilabel(app.UIFigure);
            app.tcEditFieldLabel.Text = 't/c';
            app.tcEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis2D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end