classdef analysisRotor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RotorAnalysisDropDown          matlab.ui.control.DropDown
        RotorAnalysisDropDownLabel     matlab.ui.control.Label
        thetaEditField_2               matlab.ui.control.NumericEditField
        thetaEditField_2Label          matlab.ui.control.Label
        EditField_RPM                  matlab.ui.control.NumericEditField
        RPMLabel                       matlab.ui.control.Label
        EditField_dot_theta            matlab.ui.control.NumericEditField
        dotthetaLabel                  matlab.ui.control.Label
        AngVelocityLabel               matlab.ui.control.Label
        EditField_dot_phi              matlab.ui.control.NumericEditField
        dotphiLabel                    matlab.ui.control.Label
        RotorLabel                     matlab.ui.control.Label
        AtmosphericModelDropDown       matlab.ui.control.DropDown
        AtmosphericModelDropDownLabel  matlab.ui.control.Label
        AerodynamicModelDropDown       matlab.ui.control.DropDown
        AerodynamicModelDropDownLabel  matlab.ui.control.Label
        AirfoilDropDown                matlab.ui.control.DropDown
        AirfoilDropDownLabel           matlab.ui.control.Label
        thetamEditField                matlab.ui.control.NumericEditField
        thetamEditFieldLabel           matlab.ui.control.Label
        epsilonEditField               matlab.ui.control.NumericEditField
        epsilonEditFieldLabel          matlab.ui.control.Label
        TipLossesSwitch                matlab.ui.control.Switch
        TipLossesSwitchLabel           matlab.ui.control.Label
        ChordEditField                 matlab.ui.control.NumericEditField
        ChordEditFieldLabel            matlab.ui.control.Label
        SpanEditField                  matlab.ui.control.NumericEditField
        SpanEditFieldLabel             matlab.ui.control.Label
        NoBladesEditField              matlab.ui.control.NumericEditField
        NoBladesEditFieldLabel         matlab.ui.control.Label
        DiscretizationLabel            matlab.ui.control.Label
        BladeEditField                 matlab.ui.control.NumericEditField
        BladeEditFieldLabel            matlab.ui.control.Label
        AzimutalEditField              matlab.ui.control.NumericEditField
        AzimutalEditFieldLabel         matlab.ui.control.Label
        thetaEditField                 matlab.ui.control.NumericEditField
        thetaEditFieldLabel            matlab.ui.control.Label
        phiEditField                   matlab.ui.control.NumericEditField
        phiEditFieldLabel              matlab.ui.control.Label
        EulerAnglesLabel               matlab.ui.control.Label
        InducedVelocityLabel           matlab.ui.control.Label
        LinVelocityLabel               matlab.ui.control.Label
        ZEditField_indvel              matlab.ui.control.NumericEditField
        ZEditField_2Label              matlab.ui.control.Label
        ZEditField_vel                 matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YEditField_indvel              matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        YEditField_vel                 matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XEditField_indvel              matlab.ui.control.NumericEditField
        XEditField_2Label              matlab.ui.control.Label
        XEditField_vel                 matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        ZMOMENTEditField               matlab.ui.control.NumericEditField
        ZMOMENTEditFieldLabel          matlab.ui.control.Label
        YMOMENTEditField               matlab.ui.control.NumericEditField
        YMOMENTEditFieldLabel          matlab.ui.control.Label
        XMOMENTEditField               matlab.ui.control.NumericEditField
        XMOMENTEditFieldLabel          matlab.ui.control.Label
        ZFORCEEditField                matlab.ui.control.NumericEditField
        ZFORCEEditFieldLabel           matlab.ui.control.Label
        YFORCEEditField                matlab.ui.control.NumericEditField
        YFORCEEditFieldLabel           matlab.ui.control.Label
        XFORCEEditField                matlab.ui.control.NumericEditField
        XFORCEEditFieldLabel           matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)

        % linear velocity
        x_linvel = 0
        y_linvel = 0
        z_linvel = 0

        % angular velocity
        dot_phi = 0
        dot_theta = 0
        RPM = 0

        % induced velocity
        x_indvel = 0
        y_indvel = 0
        z_indvel = 0

        % orientation - euler angles
        euler_phi = 0
        euler_theta = 0

        % blade proprieties
        blade_noblades = 4
        blade_span = 1
        blade_chord = 0.1
        blade_epsilon = 0
        blade_theta = 0
        blade_twistrate = 0
        blade_tiplosses = 'Off'
        blade_airfoil = 'NACA0012'


        % discretization
        azimutal_points = 250
        blade_points = 100

        % aerodynamic model
        aerodynamic_model = "NeuralFoil"

        % atmospheric model
        atmospheric_model = 'atmosisa'


        % to check if there is data to plot
        thereIsData = false

        % POLAR PLOT
        polar_plot

        ROTOR_DATA
        BLADE_DATA
        OUTPUTS_DATA
        
        
    end
    
    methods (Access = private)
        
        function polarPlot(app, azimutes, rho, values, slabel)

            % Verificar entradas
            if length(azimutes) ~= size(values, 1)
                error("O número de azimutes deve corresponder ao número de linhas em 'values'.");
            end
            if length(rho) ~= size(values, 2)
                error("O número de raios (rho) deve corresponder ao número de colunas em 'values'.");
            end
            
            % Usar o objeto polar_plot existente
            polar_ax = app.polar_plot;
            
            % % Verificar se o gráfico já foi configurado, se não, fazer a configuração inicial
            % if polar_ax.Position(3) ~= 230 || polar_ax.Position(4) ~= 230
            %     polar_ax.Units = 'pixels'; % Assegura que a posição seja em pixels
            %     polar_ax.Position = [280 25 230 230]; % Definir uma posição fixa e maior para o gráfico
            % end
            
            % Limpar o gráfico anterior
            cla(polar_ax);
            
            % Expandir azimutes e rho para matrizes
            [azimutesGrid, rhoGrid] = meshgrid(azimutes, rho);
            
            % Linearizar as matrizes para o uso no scatter
            azimutesVector = azimutesGrid(:);
            rhoVector = rhoGrid(:);
            valuesVector = values(:);
            
            % Plotar o gráfico polar
            scatter(polar_ax, azimutesVector, rhoVector, 50, valuesVector, 'filled');
            
            % Configurações de aparência
            colormap(polar_ax, 'turbo');
            polar_ax.ThetaZeroLocation = 'top'; % Ajustar orientação do norte
            thetaticks(polar_ax, 0:90:360); % Marcar ângulos principais
            rticks(polar_ax, []); % Opcional: remover marcas radiais
            rticklabels(polar_ax, {}); % Opcional: remover rótulos radiais
            
            % Barra de cores (não redefinir, apenas garantir que esteja visível)
            colorbarHandle = colorbar(polar_ax);
            ylabel(colorbarHandle, slabel, 'FontSize', 12); % Legenda da barra de cor
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.polar_plot = polaraxes(app.UIFigure);
            app.polar_plot.Units = "pixels";
            app.polar_plot.Position = [280 35 230 230];
            
            % polarplot(app.polar_plot,[],[]);

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            clc

            app.thereIsData = false;

            fprintf('*******************************\n');
            fprintf('*            INPUTS           *\n');
            fprintf('*******************************\n\n');

            % Display linear velocity
            fprintf('=== Linear Velocity  ===\n');
            fprintf('Forward velocity: %.2f m/s\n', app.x_linvel);
            fprintf('Lateral velocity: %.2f m/s\n', app.y_linvel);
            fprintf('Vertical velocity: %.2f m/s\n', app.z_linvel);

            % Display linear velocity
            fprintf('=== Linear Velocity  ===\n');
            fprintf('Angular Velocity - X: %.2f rad/s\n', app.dot_phi);
            fprintf('Angular Velocity - Y: %.2f rad/s\n', app.dot_theta);
            fprintf('RPM: %.2f rad/s\n', app.RPM);
            
            % Display induced velocity
            fprintf('\n=== Induced Velocity ===\n');
            fprintf('Induced Velocity - X: %.2f m/s\n', app.x_indvel);
            fprintf('Induced Velocity - Y: %.2f m/s\n', app.y_indvel);
            fprintf('Induced Velocity - Z: %.2f m/s\n', app.z_indvel);
            
            % Display orientation - Euler angles
            fprintf('\n=== Orientation - Euler Angles ===\n');
            fprintf('Roll angle: %.4f º\n', app.euler_phi);
            fprintf('Pitch angle: %.4f º\n', app.euler_theta);
            
            % Display blade properties
            fprintf('\n=== Blade Properties ===\n');
            fprintf('  Number of blades: %d\n', app.blade_noblades);
            fprintf('  Blade span: %.2f m\n', app.blade_span);
            fprintf('  Blade chord: %.2f m\n', app.blade_chord);
            fprintf('  Blade pitch angle: %.2f\n', app.blade_theta);
            fprintf('  Blade root distance: %.2f m\n', app.blade_epsilon);
            fprintf('  Blade twist rate : %.4f º/m\n', app.blade_twistrate);
            fprintf('  Tip losses: %s\n', app.blade_tiplosses);
            fprintf('  Blade airfoil: %s\n', app.blade_airfoil);
            
            % Display discretization
            fprintf('\n=== Discretization ===\n');
            fprintf('  Azimutal points (per revolution): %d\n', app.azimutal_points);
            fprintf('  Blade spanwise points: %d\n', app.blade_points);
            
            % Display aerodynamic model
            fprintf('\n=== Aerodynamic Model ===\n');
            fprintf('  Model: %s\n', app.aerodynamic_model);
            
            % Display atmospheric model
            fprintf('\n=== Atmospheric Model ===\n');
            fprintf('  Model: %s\n', app.atmospheric_model);


            % Vechicle Velocity in Inertial Frame
            VEHICLE.inertial_velocity = [app.x_linvel; 
                                         app.y_linvel; 
                                         app.z_linvel];
            
            % Angular velocity            
            VEHICLE.angular_velocity = [app.dot_phi; 
                                        app.dot_theta; 
                                        app.RPM * pi/180];
            
            % Orientation - Euler Angkes
            VEHICLE.orientation = [app.euler_phi; 
                                   app.euler_theta; 
                                   0]; 
            
            % ROTOR INDUCED VELOCITY
            ROTOR.v_induced = [app.x_indvel;
                               app.y_indvel;
                               app.z_indvel];
            
            % No. of blades
            ROTOR.Nb = app.blade_noblades;

            % Azimutal Discretization            
            ROTOR.azimutal_points = app.azimutal_points;
            ROTOR.azimutal_positions = azimutalDescretization(ROTOR.azimutal_points, 0);


            % R_r:b
            for k = 1:length(ROTOR.azimutal_positions)    
                ROTOR.R_r_b(:, :, k) = rotationMatrix_generator(ROTOR.azimutal_positions(k), 0, 0);
            end
            
            BLADE.chord = app.blade_chord;
            BLADE.Span = app.blade_span;
            BLADE.No_elements = app.blade_points;

            if strcmp(app.blade_tiplosses, 'On')
                BLADE.prandtlTipLosses = true;
            else
                BLADE.prandtlTipLosses = false;
            end

            BLADE.dy = BLADE.Span / BLADE.No_elements;

            BLADE.RootBladeDistance = app.blade_epsilon;

            BLADE.twist_rate = app.blade_twistrate;
            

            BLADE.theta = app.blade_theta;

            BLADE.dA = BLADE.dy * BLADE.chord;
            
            BLADE.pos_sec = (0:BLADE.No_elements) * BLADE.dy + BLADE.RootBladeDistance;
            BLADE.pos_sec = [zeros(length(BLADE.pos_sec), 1), BLADE.pos_sec', zeros(length(BLADE.pos_sec), 1)];
            
            BLADE.theta = twist_distribution(BLADE);
            
            airfoil = load('./airfoils/data/naca0012.mat').airfoil;
            
            BLADE.airfoil_name = airfoil.name;
            BLADE.airfoil_data = airfoil.data;            
            
            % Inicialização da variáveis gerais do simulador            
            SIM.AerodynamicModel = app.aerodynamic_model;
            SIM.atmosphereModel = app.atmospheric_model;
            
            % ATMOSPHERE MODEL
            ATMOSPHERE = atmosphereModel(0, SIM);

            app.ROTOR_DATA = ROTOR;
            app.BLADE_DATA = BLADE;
            
            % fprintf('*******************************\n');
            % fprintf('*        SIM VARIABLES        *\n');
            % fprintf('*******************************\n\n');
            % 
            % 
            % disp(VEHICLE)
            % disp(ROTOR)
            % disp(BLADE)
            % disp(SIM)
            % disp(ATMOSPHERE)

            OUTPUTS = rotor_analysis(VEHICLE, ROTOR, BLADE, SIM, ATMOSPHERE);

            app.thereIsData = true;

            disp(OUTPUTS)

            app.OUTPUTS_DATA = OUTPUTS;


            app.RotorAnalysisDropDown.Value = "U_T";
            polarPlot(app, ROTOR.azimutal_positions, BLADE.pos_sec(:, 2), OUTPUTS.U_T, "m/s")


        end

        % Value changed function: RotorAnalysisDropDown
        function RotorAnalysisDropDownValueChanged(app, event)
            value = app.RotorAnalysisDropDown.Value;

            disp(value)

            if app.thereIsData == true
                if strcmp(value, "U_T")
                    polarPlot(app, app.ROTOR_DATA.azimutal_positions, app.BLADE_DATA.pos_sec(:, 2), app.OUTPUTS_DATA.U_T, "m/s")
        
                elseif strcmp(value, "U_P")
                    polarPlot(app, app.ROTOR_DATA.azimutal_positions, app.BLADE_DATA.pos_sec(:, 2), app.OUTPUTS_DATA.U_P, "m/s")
        
                elseif strcmp(value, "U_R")
                    polarPlot(app, app.ROTOR_DATA.azimutal_positions, app.BLADE_DATA.pos_sec(:, 2), app.OUTPUTS_DATA.U_R, "m/s")
        
                elseif strcmp(value, "Cl")
                    polarPlot(app, app.ROTOR_DATA.azimutal_positions, app.BLADE_DATA.pos_sec(:, 2), app.OUTPUTS_DATA.Cl, "")
        
                elseif strcmp(value, "Cd")
                    polarPlot(app, app.ROTOR_DATA.azimutal_positions, app.BLADE_DATA.pos_sec(:, 2), app.OUTPUTS_DATA.Cd, "")
        
                else
                    error("not available")
                end
            else
                error("data not available")
            end
        end

        % Value changed function: XEditField_vel
        function XEditField_velValueChanged(app, event)
            app.x_linvel = app.XEditField_vel.Value;                     

        end

        % Value changed function: YEditField_vel
        function YEditField_velValueChanged(app, event)
            app.y_linvel = app.YEditField_vel.Value;
            
        end

        % Value changed function: ZEditField_vel
        function ZEditField_velValueChanged(app, event)
            app.z_linvel = app.ZEditField_vel.Value;
            
        end

        % Value changed function: XEditField_indvel
        function XEditField_indvelValueChanged(app, event)
            app.x_indvel = app.XEditField_indvel.Value;
            
        end

        % Value changed function: YEditField_indvel
        function YEditField_indvelValueChanged(app, event)
            app.y_indvel = app.YEditField_indvel.Value;
            
        end

        % Value changed function: ZEditField_indvel
        function ZEditField_indvelValueChanged(app, event)
            app.z_indvel = app.ZEditField_indvel.Value;
            
        end

        % Value changed function: phiEditField
        function phiEditFieldValueChanged(app, event)
            app.euler_phi = app.phiEditField.Value;
            
        end

        % Value changed function: thetaEditField
        function thetaEditFieldValueChanged(app, event)
            app.euler_theta = app.thetaEditField.Value;
            
        end

        % Value changed function: NoBladesEditField
        function NoBladesEditFieldValueChanged(app, event)
            app.blade_noblades = app.NoBladesEditField.Value;
            
        end

        % Value changed function: SpanEditField
        function SpanEditFieldValueChanged(app, event)
            app.blade_span = app.SpanEditField.Value;
            
        end

        % Value changed function: ChordEditField
        function ChordEditFieldValueChanged(app, event)
            app.blade_chord = app.ChordEditField.Value;
            
        end

        % Value changed function: epsilonEditField
        function epsilonEditFieldValueChanged(app, event)
            app.blade_epsilon = app.epsilonEditField.Value;
            
        end

        % Value changed function: thetamEditField
        function thetamEditFieldValueChanged(app, event)
            app.blade_twistrate = app.thetamEditField.Value;
            
        end

        % Value changed function: AzimutalEditField
        function AzimutalEditFieldValueChanged(app, event)
            app.azimutal_points = app.AzimutalEditField.Value;
            
        end

        % Value changed function: BladeEditField
        function BladeEditFieldValueChanged(app, event)
            app.blade_points = app.BladeEditField.Value;
            
        end

        % Value changed function: TipLossesSwitch
        function TipLossesSwitchValueChanged(app, event)
            app.blade_tiplosses = app.TipLossesSwitch.Value;
            
        end

        % Value changed function: AirfoilDropDown
        function AirfoilDropDownValueChanged(app, event)
            app.blade_airfoil = app.AirfoilDropDown.Value;
            
        end

        % Value changed function: AerodynamicModelDropDown
        function AerodynamicModelDropDownValueChanged(app, event)
            app.aerodynamic_model = app.AerodynamicModelDropDown.Value;
            
        end

        % Value changed function: AtmosphericModelDropDown
        function AtmosphericModelDropDownValueChanged(app, event)
            app.atmospheric_model = app.AtmosphericModelDropDown.Value;
            
        end

        % Value changed function: EditField_dot_phi
        function EditField_dot_phiValueChanged(app, event)
            app.dot_phi = app.EditField_dot_phi.Value;
            
        end

        % Value changed function: EditField_dot_theta
        function EditField_dot_thetaValueChanged(app, event)
            app.dot_theta = app.EditField_dot_theta.Value;
            
        end

        % Value changed function: EditField_RPM
        function EditField_RPMValueChanged(app, event)
            app.RPM = app.EditField_RPM.Value;
            
        end

        % Value changed function: thetaEditField_2
        function thetaEditField_2ValueChanged(app, event)
            app.blade_theta = app.thetaEditField_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '___________________________________________________________________________________';
            app.XFORCEEditFieldLabel = uilabel(app.UIFigure);
            app.XFORCEEditFieldLabel.Text = 'X-FORCE';
            app.XFORCEEditField = uieditfield(app.UIFigure, 'numeric');
            app.YFORCEEditFieldLabel = uilabel(app.UIFigure);
            app.YFORCEEditFieldLabel.Text = 'Y-FORCE';
            app.YFORCEEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZFORCEEditFieldLabel = uilabel(app.UIFigure);
            app.ZFORCEEditFieldLabel.Text = 'Z-FORCE';
            app.ZFORCEEditField = uieditfield(app.UIFigure, 'numeric');
            app.XMOMENTEditFieldLabel = uilabel(app.UIFigure);
            app.XMOMENTEditFieldLabel.Text = 'X-MOMENT';
            app.XMOMENTEditField = uieditfield(app.UIFigure, 'numeric');
            app.YMOMENTEditFieldLabel = uilabel(app.UIFigure);
            app.YMOMENTEditFieldLabel.Text = 'Y-MOMENT';
            app.YMOMENTEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZMOMENTEditFieldLabel = uilabel(app.UIFigure);
            app.ZMOMENTEditFieldLabel.Text = 'Z-MOMENT';
            app.ZMOMENTEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField_vel = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_vel.ValueChangedFcn = createCallbackFcn(app, @XEditField_velValueChanged, true);
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_indvel = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_indvel.ValueChangedFcn = createCallbackFcn(app, @XEditField_indvelValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField_vel = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_vel.ValueChangedFcn = createCallbackFcn(app, @YEditField_velValueChanged, true);
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_indvel = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_indvel.ValueChangedFcn = createCallbackFcn(app, @YEditField_indvelValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField_vel = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_vel.ValueChangedFcn = createCallbackFcn(app, @ZEditField_velValueChanged, true);
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_indvel = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_indvel.ValueChangedFcn = createCallbackFcn(app, @ZEditField_indvelValueChanged, true);
            app.LinVelocityLabel = uilabel(app.UIFigure);
            app.LinVelocityLabel.Text = 'Lin. Velocity';
            app.InducedVelocityLabel = uilabel(app.UIFigure);
            app.InducedVelocityLabel.Text = 'Induced Velocity';
            app.EulerAnglesLabel = uilabel(app.UIFigure);
            app.EulerAnglesLabel.Text = 'Euler Angles';
            app.phiEditFieldLabel = uilabel(app.UIFigure);
            app.phiEditFieldLabel.Interpreter = 'latex';
            app.phiEditFieldLabel.Text = '\phi';
            app.phiEditField = uieditfield(app.UIFigure, 'numeric');
            app.phiEditField.ValueChangedFcn = createCallbackFcn(app, @phiEditFieldValueChanged, true);
            app.thetaEditFieldLabel = uilabel(app.UIFigure);
            app.thetaEditFieldLabel.Interpreter = 'latex';
            app.thetaEditFieldLabel.Text = '\theta';
            app.thetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.thetaEditField.ValueChangedFcn = createCallbackFcn(app, @thetaEditFieldValueChanged, true);
            app.AzimutalEditFieldLabel = uilabel(app.UIFigure);
            app.AzimutalEditFieldLabel.Text = 'Azimutal';
            app.AzimutalEditField = uieditfield(app.UIFigure, 'numeric');
            app.AzimutalEditField.ValueChangedFcn = createCallbackFcn(app, @AzimutalEditFieldValueChanged, true);
            app.AzimutalEditField.Value = 250;
            app.BladeEditFieldLabel = uilabel(app.UIFigure);
            app.BladeEditFieldLabel.Text = 'Blade';
            app.BladeEditField = uieditfield(app.UIFigure, 'numeric');
            app.BladeEditField.ValueChangedFcn = createCallbackFcn(app, @BladeEditFieldValueChanged, true);
            app.BladeEditField.Value = 100;
            app.DiscretizationLabel = uilabel(app.UIFigure);
            app.DiscretizationLabel.Text = 'Discretization';
            app.NoBladesEditFieldLabel = uilabel(app.UIFigure);
            app.NoBladesEditFieldLabel.Text = 'No. Blades';
            app.NoBladesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoBladesEditField.ValueChangedFcn = createCallbackFcn(app, @NoBladesEditFieldValueChanged, true);
            app.NoBladesEditField.Value = 4;
            app.SpanEditFieldLabel = uilabel(app.UIFigure);
            app.SpanEditFieldLabel.Text = 'Span';
            app.SpanEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpanEditField.ValueChangedFcn = createCallbackFcn(app, @SpanEditFieldValueChanged, true);
            app.SpanEditField.Value = 1;
            app.ChordEditFieldLabel = uilabel(app.UIFigure);
            app.ChordEditFieldLabel.Text = 'Chord';
            app.ChordEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChordEditField.ValueChangedFcn = createCallbackFcn(app, @ChordEditFieldValueChanged, true);
            app.ChordEditField.Value = 0.1;
            app.TipLossesSwitchLabel = uilabel(app.UIFigure);
            app.TipLossesSwitchLabel.Text = 'Tip Losses';
            app.TipLossesSwitch = uiswitch(app.UIFigure, 'slider');
            app.TipLossesSwitch.ValueChangedFcn = createCallbackFcn(app, @TipLossesSwitchValueChanged, true);
            app.epsilonEditFieldLabel = uilabel(app.UIFigure);
            app.epsilonEditFieldLabel.Interpreter = 'latex';
            app.epsilonEditFieldLabel.Text = '\epsilon';
            app.epsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.epsilonEditField.ValueChangedFcn = createCallbackFcn(app, @epsilonEditFieldValueChanged, true);
            app.thetamEditFieldLabel = uilabel(app.UIFigure);
            app.thetamEditFieldLabel.Interpreter = 'latex';
            app.thetamEditFieldLabel.Text = '\theta/m';
            app.thetamEditField = uieditfield(app.UIFigure, 'numeric');
            app.thetamEditField.ValueChangedFcn = createCallbackFcn(app, @thetamEditFieldValueChanged, true);
            app.AirfoilDropDownLabel = uilabel(app.UIFigure);
            app.AirfoilDropDownLabel.Text = 'Airfoil';
            app.AirfoilDropDown = uidropdown(app.UIFigure);
            app.AirfoilDropDown.Items = {'NACA0012'};
            app.AirfoilDropDown.ValueChangedFcn = createCallbackFcn(app, @AirfoilDropDownValueChanged, true);
            app.AirfoilDropDown.Value = 'NACA0012';
            app.AerodynamicModelDropDownLabel = uilabel(app.UIFigure);
            app.AerodynamicModelDropDownLabel.Text = 'Aerodynamic Model';
            app.AerodynamicModelDropDown = uidropdown(app.UIFigure);
            app.AerodynamicModelDropDown.Items = {'NeuralFoil', 'XFoil'};
            app.AerodynamicModelDropDown.ValueChangedFcn = createCallbackFcn(app, @AerodynamicModelDropDownValueChanged, true);
            app.AerodynamicModelDropDown.Value = 'NeuralFoil';
            app.AtmosphericModelDropDownLabel = uilabel(app.UIFigure);
            app.AtmosphericModelDropDownLabel.Text = 'Atmospheric Model';
            app.AtmosphericModelDropDown = uidropdown(app.UIFigure);
            app.AtmosphericModelDropDown.Items = {'atmosisa'};
            app.AtmosphericModelDropDown.ValueChangedFcn = createCallbackFcn(app, @AtmosphericModelDropDownValueChanged, true);
            app.AtmosphericModelDropDown.Value = 'atmosisa';
            app.RotorLabel = uilabel(app.UIFigure);
            app.RotorLabel.Text = 'Rotor';
            app.dotphiLabel = uilabel(app.UIFigure);
            app.dotphiLabel.Interpreter = 'latex';
            app.dotphiLabel.Text = '\dot{\phi}';
            app.EditField_dot_phi = uieditfield(app.UIFigure, 'numeric');
            app.EditField_dot_phi.ValueChangedFcn = createCallbackFcn(app, @EditField_dot_phiValueChanged, true);
            app.AngVelocityLabel = uilabel(app.UIFigure);
            app.AngVelocityLabel.Text = 'Ang. Velocity';
            app.dotthetaLabel = uilabel(app.UIFigure);
            app.dotthetaLabel.Interpreter = 'latex';
            app.dotthetaLabel.Text = '\dot{\theta}';
            app.EditField_dot_theta = uieditfield(app.UIFigure, 'numeric');
            app.EditField_dot_theta.ValueChangedFcn = createCallbackFcn(app, @EditField_dot_thetaValueChanged, true);
            app.RPMLabel = uilabel(app.UIFigure);
            app.RPMLabel.Text = 'RPM';
            app.EditField_RPM = uieditfield(app.UIFigure, 'numeric');
            app.EditField_RPM.ValueChangedFcn = createCallbackFcn(app, @EditField_RPMValueChanged, true);
            app.thetaEditField_2Label = uilabel(app.UIFigure);
            app.thetaEditField_2Label.Interpreter = 'latex';
            app.thetaEditField_2Label.Text = '\theta';
            app.thetaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.thetaEditField_2.ValueChangedFcn = createCallbackFcn(app, @thetaEditField_2ValueChanged, true);
            app.RotorAnalysisDropDownLabel = uilabel(app.UIFigure);
            app.RotorAnalysisDropDownLabel.Text = 'Rotor Analysis';
            app.RotorAnalysisDropDown = uidropdown(app.UIFigure);
            app.RotorAnalysisDropDown.Items = {'U_T', 'U_P', 'U_R', 'Cl', 'Cd'};
            app.RotorAnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @RotorAnalysisDropDownValueChanged, true);
            app.RotorAnalysisDropDown.Value = 'U_T';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysisRotor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end